classdef HXApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Piezo2TabGroup                 matlab.ui.container.TabGroup
        Piezo2                         matlab.ui.container.Tab
        Phase2                         matlab.ui.control.NumericEditField
        PhasedegEditFieldLabel_4       matlab.ui.control.Label
        Amplitude2                     matlab.ui.control.NumericEditField
        AmplitudePPVEditFieldLabel_4   matlab.ui.control.Label
        Frequency2                     matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel_4    matlab.ui.control.Label
        Enable2                        matlab.ui.control.Switch
        Piezo2FreqSweep                matlab.ui.container.Tab
        MaxFreqHz2                     matlab.ui.control.NumericEditField
        MaxFreqHzEditField_2Label      matlab.ui.control.Label
        MinFreqHz2                     matlab.ui.control.NumericEditField
        MinFreqHzEditField_2Label      matlab.ui.control.Label
        FreqStepHz2                    matlab.ui.control.NumericEditField
        FreqstepHzLabel_2              matlab.ui.control.Label
        FreqTimesteps2                 matlab.ui.control.NumericEditField
        TimestepsEditField_2Label      matlab.ui.control.Label
        ActivateFrequency2Sweep        matlab.ui.control.CheckBox
        Piezo2AmpSweep                 matlab.ui.container.Tab
        AmplitudeTimesteps2            matlab.ui.control.NumericEditField
        TimestepsLabel_2               matlab.ui.control.Label
        AmplitudeStepV2                matlab.ui.control.NumericEditField
        AmplitudestepVLabel            matlab.ui.control.Label
        MaxAmplitudeV2                 matlab.ui.control.NumericEditField
        MaxAmplitudeVEditFieldLabel_2  matlab.ui.control.Label
        MinAmplitudeV2                 matlab.ui.control.NumericEditField
        MinAmplitudeVEditFieldLabel_2  matlab.ui.control.Label
        ActivateAmplitude2Sweep        matlab.ui.control.CheckBox
        Piezo2PhaseSweep               matlab.ui.container.Tab
        PhaseTimesteps2                matlab.ui.control.NumericEditField
        TimestepsEditFieldLabel_3      matlab.ui.control.Label
        Phasestepdeg2                  matlab.ui.control.NumericEditField
        PhasestepdegEditFieldLabel_2   matlab.ui.control.Label
        MaxPhasedeg2                   matlab.ui.control.NumericEditField
        MaxPhasedegEditFieldLabel_2    matlab.ui.control.Label
        MinPhasedeg2                   matlab.ui.control.NumericEditField
        MinPhasedegEditFieldLabel_2    matlab.ui.control.Label
        ActivatePhase2Sweep            matlab.ui.control.CheckBox
        Piezo1TabGroup                 matlab.ui.container.TabGroup
        Piezo1                         matlab.ui.container.Tab
        Phase1                         matlab.ui.control.NumericEditField
        PhasedegEditFieldLabel_3       matlab.ui.control.Label
        Amplitude1                     matlab.ui.control.NumericEditField
        AmplitudePPVEditFieldLabel_3   matlab.ui.control.Label
        Frequency1                     matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel_3    matlab.ui.control.Label
        Enable1                        matlab.ui.control.Switch
        Piezo1FreqSweep                matlab.ui.container.Tab
        MaxFreqHz1                     matlab.ui.control.NumericEditField
        MaxFreqHzLabel                 matlab.ui.control.Label
        MinFreqHz1                     matlab.ui.control.NumericEditField
        MinFreqHzLabel                 matlab.ui.control.Label
        FreqStepHz1                    matlab.ui.control.NumericEditField
        FreqstepHzLabel                matlab.ui.control.Label
        ActivateFrequency1Sweep        matlab.ui.control.CheckBox
        FreqTimesteps1                 matlab.ui.control.NumericEditField
        TimestepsEditFieldLabel        matlab.ui.control.Label
        Piezo1AmpSweep                 matlab.ui.container.Tab
        AmplitudeTimesteps1            matlab.ui.control.NumericEditField
        TimestepsLabel                 matlab.ui.control.Label
        AmplitudeStepV1                matlab.ui.control.NumericEditField
        AmplitudestepVLabel_2          matlab.ui.control.Label
        MaxAmplitudeV1                 matlab.ui.control.NumericEditField
        MaxAmplitudeVEditFieldLabel    matlab.ui.control.Label
        MinAmplitudeV1                 matlab.ui.control.NumericEditField
        MinAmplitudeVEditFieldLabel    matlab.ui.control.Label
        ActivateAmplitude1Sweep        matlab.ui.control.CheckBox
        Piezo1PhaseSweep               matlab.ui.container.Tab
        PhaseTimesteps1                matlab.ui.control.NumericEditField
        TimestepsEditFieldLabel_2      matlab.ui.control.Label
        Phasestepdeg1                  matlab.ui.control.NumericEditField
        PhasestepdegEditFieldLabel     matlab.ui.control.Label
        MaxPhasedeg1                   matlab.ui.control.NumericEditField
        MaxPhasedegEditFieldLabel      matlab.ui.control.Label
        MinPhasedeg1                   matlab.ui.control.NumericEditField
        MinPhasedegEditFieldLabel      matlab.ui.control.Label
        ActivatePhase1Sweep            matlab.ui.control.CheckBox
        UpdateControlsPanel            matlab.ui.container.Panel
        SendControlstoTeensyButton     matlab.ui.control.Button
        StartStopSwitch                matlab.ui.control.Switch
        SwitchBackgroundColor          matlab.ui.control.Image
        HeaterControlPanel             matlab.ui.container.Panel
        RopeHeaterSwitch               matlab.ui.control.Switch
        RopeHeaterSwitchLabel          matlab.ui.control.Label
        HeaterModulesSwitch            matlab.ui.control.Switch
        HeaterModulesSwitchLabel       matlab.ui.control.Label
        InletFluidTemp                 matlab.ui.control.NumericEditField
        InletFluidTempCEditFieldLabel  matlab.ui.control.Label
        HeatFlux                       matlab.ui.control.NumericEditField
        HeatFluxWcm2EditFieldLabel     matlab.ui.control.Label
        TeensyConnectionSettingsPanel  matlab.ui.container.Panel
        ConnectButton                  matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        DefaultConnectSettings         matlab.ui.control.CheckBox
        BaudrateDropDown               matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        COMPortDropDown                matlab.ui.control.DropDown
        COMPortDropDownLabel           matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        boilSurfacePlot                matlab.ui.control.UIAxes
        flowRatePlot                   matlab.ui.control.UIAxes
        outletPressurePlot             matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        AverageHeaterModuleTempCPanel  matlab.ui.container.Panel
        avgHeaterTemp                  matlab.ui.control.Label
        avgHeaterTempGage              matlab.ui.control.Gauge
        DebugWindowPanel               matlab.ui.container.Panel
        DebugTextArea                  matlab.ui.control.TextArea
        InletFluidTempCPanel           matlab.ui.container.Panel
        InletTemp                      matlab.ui.control.Label
        InletTempGage                  matlab.ui.control.Gauge
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        iTextBox        % Line in debug textbox
        configuration   % Variables relating to current configuration
        variables       % Misc variables
        teensy          % Teensy object
        debugFile       % Debug file
        plotTimer       % Timer object for updating plots/visuals
        outletPressureVaporLineObject       % Line object for outletPressurePlot vapor
        outletPressureLiquidLineObject      % Line object for outletPressurePlot vapor
        boilSurfaceLineObject1              % Line object for boilSurfacePlot 1
        boilSurfaceLineObject2              % Line object for boilSurfacePlot 2
        boilSurfaceLineObject3              % Line object for boilSurfacePlot 3
        boilSurfaceLineObject4              % Line object for boilSurfacePlot 4
        boilSurfaceLineObjectAvg            % Line object for boilSurfacePlot avg
        flowRateLineObject                  % Line object for flowRatePlot
        
        sweep               % Struct for sweeping properties
        sweepFreqTimer1     % Timer for sweeping frequency for piezo 1
        sweepAmpTimer1      % Timer for sweeping amplitude for piezo 1
        sweepPhaseTimer1    % Timer for sweeping phase for piezo 1
        sweepFreqTimer2     % Timer for sweeping frequency for piezo 2
        sweepAmpTimer2      % Timer for sweeping amplitude for piezo 2
        sweepPhaseTimer2    % Timer for sweeping phase for piezo 2
        
        sweepBlinkColor     % Timer to blink update panel yellow
    end
    
    methods (Access = private)
             
        function getSerialPortList(app)
            displayDebug(app, 'Fetching COM settings');
            serialPorts = serialportlist("available");
            list = ["Select"]; %#ok<NBRAK> 
            for i=1:length(serialPorts)
                list(end+1) = serialPorts(i); %#ok<AGROW> 
            end
            app.configuration.comList       = list;
            app.configuration.baudList      = ["Select", "1200", "2400", "4800", "9600", "19200", "38400", "57600", "115200"];
            app.BaudrateDropDown.Items      = app.configuration.baudList;
            app.COMPortDropDown.Items       = app.configuration.comList;
            app.BaudrateDropDown.Value      = "Select";
            app.COMPortDropDown.Value       = "Select";
            displayDebug(app, 'Fetched COM settings');
        end
        
        function displayDebug(app, message)
            fullMessage = strjoin({datestr(now,'HH:MM:SS.FFF') ':' message});
            % Display to the dialogue box in the app
            app.iTextBox = app.iTextBox + 1;
            app.DebugTextArea.Value(app.iTextBox) = {fullMessage};
            % Save to debugFile property for final debug file
            app.debugFile.text(app.iTextBox) = {fullMessage};
        end
        
        function initTeensyConnection(app)
            displayDebug(app, 'Starting Teensy connection');
            app.teensy = serialport(app.configuration.comPort, app.configuration.BAUD);
            configureTerminator(app.teensy, "CR/LF");
            configureCallback(app.teensy, "terminator", @app.runDataAquisition)
            flush(app.teensy);
            displayDebug(app, 'Successful connection to Teensy');
            app.COMPortDropDown.Value   = app.configuration.comPort;
            app.BaudrateDropDown.Value  = num2str(app.configuration.BAUD);
            initDataAquisition(app);
        end
        
        function initDataAquisition(app)
            app.teensy.UserData = struct("time",[], ...
                                         "inletPressureUpstream",[], ...
                                         "inletPressureDownstream",[], ...
                                         "outletPressureVapor",[], ...
                                         "outletPressureLiquid",[], ...
                                         "heatFlux",[], ...
                                         "heaterTemperature1",[], ...
                                         "heaterTemperature2",[], ...
                                         "heaterTemperature3",[], ...
                                         "heaterTemperature4",[], ...
                                         "heaterTemperature5",[], ...
                                         "boilSurfaceTemperature1",[], ...
                                         "boilSurfaceTemperature2",[], ...
                                         "boilSurfaceTemperature3",[], ...
                                         "boilSurfaceTemperature4",[], ...
                                         "averageBoilSurfaceTemp",[], ...
                                         "inletFlowRate",[], ...
                                         "inletFluidTemperature",[], ...
                                         "targetFluidTemperature",[], ...
                                         "frequency1",[], ...
                                         "frequency2",[], ...
                                         "amplitude1",[], ...
                                         "amplitude2",[], ...
                                         "phase1",[], ...
                                         "phase2",[], ...
                                         "enable1",[], ...
                                         "enable2",[], ...
                                         "Kp",[], ...
                                         "Kd",[], ...
                                         "Ki",[], ...
                                         "endTesting", []);
        end

        function saveData(app)
            % Save to .mat file
            displayDebug(app, strjoin({'Saving data to' app.variables.fileNameMat}));
            saveStruct = app.teensy.UserData;
            save(app.variables.fileNameMat, '-struct', 'saveStruct');
            
            % Save to .csv file
            displayDebug(app, strjoin({'Saving data to' app.variables.fileName}));
            s = app.teensy.UserData;
            FID = fopen(app.variables.fileName,'w');    % open file at the filename
            headers = fieldnames(s);                    % Get the fieldnames in struct
            m = length(headers);
            sz = zeros(m,2);
            t = length(s);
            % Modify mx0 to 0xm data
            for q=1:m
               s.(headers{q}) = s.(headers{q})'; 
            end
            
            for rr = 1:t
                l = '';
                for ii = 1:m
                    sz(ii,:) = size(s(rr).(headers{ii}));   
                    if ischar(s(rr).(headers{ii}))
                        sz(ii,2) = 1;
                    end
                    l = [l,'"',headers{ii},'",',repmat(',',1,sz(ii,2)-1)]; %#ok<AGROW> 
                end
                l = [l,'\n']; %#ok<AGROW> 
                fprintf(FID,l);
                n = max(sz(:,1));
                for ii = 1:n
                    l = '';
                    for jj = 1:m
                        c = s(rr).(headers{jj});
                        str = '';
                        
                        if sz(jj,1)<ii
                            str = repmat(',',1,sz(jj,2));
                        else
                            if isnumeric(c)
                                for kk = 1:sz(jj,2)
                                    str = [str,num2str(c(ii,kk)),',']; %#ok<AGROW> 
                                end
                            elseif islogical(c)
                                for kk = 1:sz(jj,2)
                                    str = [str,num2str(double(c(ii,kk))),',']; %#ok<AGROW> 
                                end
                            elseif ischar(c)
                                str = ['"',c(ii,:),'",'];
                            elseif iscell(c)
                                if isnumeric(c{1,1})
                                    for kk = 1:sz(jj,2)
                                        str = [str,num2str(c{ii,kk}),',']; %#ok<AGROW> 
                                    end
                                elseif islogical(c{1,1})
                                    for kk = 1:sz(jj,2)
                                        str = [str,num2str(double(c{ii,kk})),',']; %#ok<AGROW> 
                                    end
                                elseif ischar(c{1,1})
                                    for kk = 1:sz(jj,2)
                                        str = [str,'"',c{ii,kk},'",']; %#ok<AGROW> 
                                    end
                                end
                            end
                        end
                        l = [l,str]; %#ok<AGROW> 
                    end
                    l = [l,'\n']; %#ok<AGROW> 
                    fprintf(FID,l);
                end
                fprintf(FID,'\n');
            end
            fclose(FID);
            initDataAquisition(app);                        % Re-Initiate the data struct (clear the data)
            displayDebug(app, 'Reset data struct');
        end

        function sendDataTeensy(app)
            % Get current values for vars to send
            if (app.Enable1.Value == "On")
                app.variables.enable1 = 1;
            else
                app.variables.enable1 = 0;
            end
            if (app.Enable2.Value == "On")
                app.variables.enable2 = 1;
            else
                app.variables.enable2 = 0;
            end
            app.variables.frequency1        = app.Frequency1.Value;
            app.variables.amplitude1        = app.Amplitude1.Value;
            app.variables.phase1            = app.Phase1.Value;
            app.variables.frequency2        = app.Frequency2.Value;
            app.variables.amplitude2        = app.Amplitude2.Value;
            app.variables.phase2            = app.Phase2.Value;
            app.variables.heatFlux          = app.HeatFlux.Value;
            app.variables.targetFluidTemp   = app.InletFluidTemp.Value;
            if (app.HeaterModulesSwitch.Value == "On")
                app.variables.enableHeaters = 1;
            else
                app.variables.enableHeaters = 0;
            end
            if (app.RopeHeaterSwitch.Value == "On")
                app.variables.enableRopeHeater = 1;
            else
                app.variables.enableRopeHeater = 0;
            end
            % Create list of variables to send to Teensy
            sendList         = [app.variables.enable1, ...
                                app.variables.frequency1, ...
                                app.variables.amplitude1, ...
                                app.variables.phase1, ...
                                app.variables.enable2, ...
                                app.variables.frequency2, ...
                                app.variables.amplitude2, ...
                                app.variables.phase2, ...
                                app.variables.heatFlux, ...
                                app.variables.targetFluidTemp, ...
                                app.variables.enableHeaters, ...
                                app.variables.enableRopeHeater, ...
                                app.variables.Kp, ...
                                app.variables.Kd, ...
                                app.variables.Ki, ...
                                app.variables.endTesting];
            % Construct string to send via serial
            sendString = append(strjoin(string(sendList), app.variables.teensy_delimiter), app.variables.teensy_terminator);
            writeline(app.teensy, sendString);       % Send string via serial
        end

        function runDataAquisition(app,~,~)
            % Check if the Start switch is activated, if so then aquire
            % data
            if (app.StartStopSwitch.Value == "Start" || app.variables.firstData)
                data = str2double(split(readline(app.teensy), ","));
                i = 1;
                app.teensy.UserData.time(end+1)                     = data(i); i=i+1;
                app.teensy.UserData.inletPressureUpstream(end+1)    = data(i); i=i+1;
                app.teensy.UserData.inletPressureDownstream(end+1)  = data(i); i=i+1;
                app.teensy.UserData.outletPressureVapor(end+1)      = data(i); i=i+1;
                app.teensy.UserData.outletPressureLiquid(end+1)     = data(i); i=i+1;
                app.teensy.UserData.heatFlux(end+1)                 = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature1(end+1)       = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature2(end+1)       = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature3(end+1)       = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature4(end+1)       = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature5(end+1)       = data(i); i=i+1;
                app.teensy.UserData.boilSurfaceTemperature1(end+1)  = data(i); i=i+1;
                app.teensy.UserData.boilSurfaceTemperature2(end+1)  = data(i); i=i+1;
                app.teensy.UserData.boilSurfaceTemperature3(end+1)  = data(i); i=i+1;
                app.teensy.UserData.boilSurfaceTemperature4(end+1)  = data(i); i=i+1;
                app.teensy.UserData.averageBoilSurfaceTemp(end+1)   = data(i); i=i+1;
                app.teensy.UserData.inletFlowRate(end+1)            = data(i); i=i+1;
                app.teensy.UserData.inletFluidTemperature(end+1)    = data(i); i=i+1;
                app.teensy.UserData.targetFluidTemperature(end+1)   = data(i); i=i+1;
                app.teensy.UserData.frequency1(end+1)               = data(i); i=i+1;
                app.teensy.UserData.frequency2(end+1)               = data(i); i=i+1;
                app.teensy.UserData.amplitude1(end+1)               = data(i); i=i+1;
                app.teensy.UserData.amplitude2(end+1)               = data(i); i=i+1;
                app.teensy.UserData.phase1(end+1)                   = data(i); i=i+1;
                app.teensy.UserData.phase2(end+1)                   = data(i); i=i+1;
                app.teensy.UserData.enable1(end+1)                  = data(i); i=i+1;
                app.teensy.UserData.enable2(end+1)                  = data(i); i=i+1;
                app.teensy.UserData.Kp(end+1)                       = data(i); i=i+1;
                app.teensy.UserData.Kd(end+1)                       = data(i); i=i+1;
                app.teensy.UserData.Ki(end+1)                       = data(i);
                app.teensy.UserData.endTesting(end+1)               = app.variables.endTesting;
                
                t = app.teensy.UserData.time(end) - app.teensy.UserData.time(1);
                % Add points to pressure plot
                addpoints(app.outletPressureLiquidLineObject, t, app.teensy.UserData.outletPressureLiquid(end));
                addpoints(app.outletPressureVaporLineObject, t, app.teensy.UserData.outletPressureVapor(end));
                
                % Add points to flow rate plot
                addpoints(app.flowRateLineObject, t, app.teensy.UserData.inletFlowRate(end));
                
                % Add points to boil surface plot
                addpoints(app.boilSurfaceLineObject1, t, app.teensy.UserData.boilSurfaceTemperature1(end));
                addpoints(app.boilSurfaceLineObject2, t, app.teensy.UserData.boilSurfaceTemperature2(end));
                addpoints(app.boilSurfaceLineObject3, t, app.teensy.UserData.boilSurfaceTemperature3(end));
                addpoints(app.boilSurfaceLineObject4, t, app.teensy.UserData.boilSurfaceTemperature4(end));
                addpoints(app.boilSurfaceLineObjectAvg, t, app.teensy.UserData.averageBoilSurfaceTemp(end));
                
                % Save Kd, Kp, Ki to variables property
                app.variables.Kp = app.teensy.UserData.Kp(end);
                app.variables.Ki = app.teensy.UserData.Ki(end);
                app.variables.Kd = app.teensy.UserData.Kd(end);
                
                % If first data, update values on UI
                if (app.variables.firstData || app.variables.updateData)
                    if (app.teensy.UserData.enable1(end) == 1)
                        app.Enable1.Value               = "On";
                    else
                        app.Enable1.Value               = "Off";
                    end
                    if (app.teensy.UserData.enable2(end) == 1)
                        app.Enable2.Value               = "On";
                    else
                        app.Enable2.Value               = "Off";
                    end
                    if (app.teensy.UserData.heatFlux(end) > 0)
                        app.HeaterModulesSwitch.Value   = "On";
                    else
                        app.HeaterModulesSwitch.Value   = "Off";
                    end
                    if (app.teensy.UserData.targetFluidTemperature(end) > 0)
                        app.RopeHeaterSwitch.Value      = "On";
                    else
                        app.RopeHeaterSwitch.Value      = "Off";
                    end
                    app.Frequency1.Value            = app.teensy.UserData.frequency1(end);
                    app.Amplitude1.Value            = app.teensy.UserData.amplitude1(end);
                    app.Phase1.Value                = app.teensy.UserData.phase1(end);
                    app.Frequency2.Value            = app.teensy.UserData.frequency2(end);
                    app.Amplitude2.Value            = app.teensy.UserData.amplitude2(end);
                    app.Phase2.Value                = app.teensy.UserData.phase2(end);
                    app.HeatFlux.Value              = app.teensy.UserData.heatFlux(end);
                    app.InletFluidTemp.Value        = app.teensy.UserData.targetFluidTemperature(end);
                    app.RopeHeaterSwitch.Value      = "On";
                    app.HeaterModulesSwitch.Value   = "On";
                    app.variables.firstData = false;
                    app.variables.updateData = false;
                end
            end
        end
        
        function initVisuals(app)
            % Clear the plots and points
            try
                clearpoints(app.outletPressureLiquidLineObject);
                clearpoints(app.outletPressureVaporLineObject);
                clearpoints(app.boilSurfaceLineObject1);
                clearpoints(app.boilSurfaceLineObject2);
                clearpoints(app.boilSurfaceLineObject3);
                clearpoints(app.boilSurfaceLineObject4);
                clearpoints(app.boilSurfaceLineObjectAvg);
                clearpoints(app.flowRateLineObject);
                cla(app.outletPressurePlot);
                cla(app.boilSurfacePlot);
                cla(app.flowRatePlot);
            catch
            end
            
            % Outlet pressure plot
            app.outletPressurePlot.XLimMode = 'manual';
            app.outletPressurePlot.YLimMode = 'auto';
            app.outletPressurePlot.XLim     = [0 app.configuration.plotSeconds];
            app.outletPressureVaporLineObject   = animatedline(app.outletPressurePlot,"Color", 'r',"LineStyle","-","DisplayName","Vapor");
            app.outletPressureLiquidLineObject  = animatedline(app.outletPressurePlot,"Color", 'b',"LineStyle","-","DisplayName","Liquid");
            legend(app.outletPressurePlot,'Location', 'northwest', 'Orientation', 'vertical');
            grid(app.outletPressurePlot, 'on');
            hold(app.outletPressurePlot, 'on');
            
            % Boil surface plot
            app.boilSurfacePlot.XLimMode    = 'manual';
            app.boilSurfacePlot.YLimMode    = 'auto';
            app.boilSurfacePlot.XLim        = [0 app.configuration.plotSeconds];
            app.boilSurfaceLineObject1      = animatedline(app.boilSurfacePlot,"Color", 'r',"LineStyle","--", "DisplayName","Thermistor 1");
            app.boilSurfaceLineObject2      = animatedline(app.boilSurfacePlot,"Color", 'b',"LineStyle","--", "DisplayName","Thermistor 2");
            app.boilSurfaceLineObject3      = animatedline(app.boilSurfacePlot,"Color", 'g',"LineStyle","--", "DisplayName","Thermistor 3");
            app.boilSurfaceLineObject4      = animatedline(app.boilSurfacePlot,"Color", 'y',"LineStyle","--", "DisplayName","Thermistor 4");
            app.boilSurfaceLineObjectAvg    = animatedline(app.boilSurfacePlot,"Color", 'k',"LineStyle","-","Linewidth",2.0, "DisplayName","Average");
            legend(app.boilSurfacePlot,'Location', 'northwest', 'Orientation', 'vertical');
            grid(app.boilSurfacePlot, 'on');
            hold(app.boilSurfacePlot, 'on');
            
            % Flow rate plot
            app.flowRatePlot.XLimMode       = 'manual';
            app.flowRatePlot.YLimMode       = 'auto';
            app.flowRatePlot.XLim           = [0 app.configuration.plotSeconds];
            app.flowRateLineObject          = animatedline(app.flowRatePlot,"Color", 'k',"LineStyle","-", "DisplayName","Flow Rate");
            legend(app.flowRatePlot,'Location', 'northwest', 'Orientation', 'vertical');
            grid(app.flowRatePlot, 'on');
            hold(app.flowRatePlot, 'on');
            
            % Inlet fluid temp gage and text
            app.InletTempGage.Value         = app.InletTempGage.Limits(1);
            app.InletTemp.Text              = 'NaN';
            
            % Average heater temp gage and text
            app.avgHeaterTempGage.Value     = app.avgHeaterTempGage.Limits(1);
            app.avgHeaterTemp.Text          = 'NaN';
        end
        
        function updateVisuals(app, ~, ~)
            if (length(app.teensy.UserData.time) > 2)
                % Get the correct x limits 
                timeElapsed = app.teensy.UserData.time(end)-app.teensy.UserData.time(1);
                if (timeElapsed < (app.configuration.plotSeconds*0.9))
                    Xmin = 0;
                    Xmax = app.configuration.plotSeconds;
                    %app.variables.dTime = app.teensy.UserData.time(end)-app.teensy.UserData.time(end-1);
                    %app.variables.numShowData = int8(timeElapsed/app.variables.dTime);
                else
                    Xmin = timeElapsed - app.configuration.plotSeconds;
                    Xmax = Xmin + app.configuration.plotSeconds*1.1;
                end
               
                % Outlet pressure plot
                hold(app.outletPressurePlot, 'on');
                app.outletPressurePlot.XLim = [Xmin Xmax]; 
                hold(app.outletPressurePlot, 'off');
                                
                % Boil surface temp plot
                hold(app.boilSurfacePlot, 'on');
                app.boilSurfacePlot.XLim = [Xmin Xmax];
                hold(app.boilSurfacePlot, 'off');
                
                % Inlet flow rate plot
                hold(app.flowRatePlot, 'on');
                app.flowRatePlot.XLim = [Xmin Xmax];
                hold(app.flowRatePlot, 'off');
                
                % Inlet fluid temp gage and text
                app.InletTempGage.Value         = app.teensy.UserData.inletFluidTemperature(end);
                app.InletTemp.Text              = strjoin({num2str(app.InletTempGage.Value) '(C)'});
                
                % Average heater temp gage and text
                heater1 = app.teensy.UserData.heaterTemperature1(end);
                heater2 = app.teensy.UserData.heaterTemperature2(end);
                heater3 = app.teensy.UserData.heaterTemperature3(end);
                heater4 = app.teensy.UserData.heaterTemperature4(end);
                heater5 = app.teensy.UserData.heaterTemperature5(end);
                avgHeater = (heater1 + heater2 + heater3 + heater4 + heater5)/5;
                app.avgHeaterTempGage.Value     = avgHeater;
                app.avgHeaterTemp.Text          = strjoin({num2str(app.avgHeaterTempGage.Value) '(C)'});
                
                % Draw new points quickly
                drawnow limitrate
            end
        end
        
        function initSweeps(app)
            % Function initializes the sweeping timer(s)
            displayDebug(app, 'Initiating sweep timer(s)');
            
            % Disable the "send controls to teensy" button and set to yellow
            app.SendControlstoTeensyButton.Enable = 0;
            app.UpdateControlsPanel.BackgroundColor = app.variables.yellowColor;
            
            % Start timer(s)
            % Frequency 1
            if (app.ActivateFrequency1Sweep.Value)
                if (strcmp(get(app.sweepFreqTimer1, 'Running'), 'off'))
                    app.sweep.freqCount1 = 0;
                    app.sweepFreqTimer1.Period = app.FreqTimesteps1.Value;
                    start(app.sweepFreqTimer1);
                    displayDebug(app, 'Started sweep for piezo 1 frequency');
                end
            else
                if (strcmp(get(app.sweepFreqTimer1, 'Running'), 'on'))
                    stop(app.sweepFreqTimer1);
                    displayDebug(app, 'Stopped sweep for piezo 1 frequency');
                end
            end
            % Amplitude 1
            if (app.ActivateAmplitude1Sweep.Value)
                if (strcmp(get(app.sweepAmpTimer1, 'Running'), 'off'))
                    app.sweep.ampCount1 = 0;
                    app.sweepAmpTimer1.Period = app.AmplitudeTimesteps1.Value;
                    start(app.sweepAmpTimer1);
                    displayDebug(app, 'Started sweep for piezo 1 amplitude');
                end
            else
                if (strcmp(get(app.sweepAmpTimer1, 'Running'), 'on'))
                    stop(app.sweepAmpTimer1);
                    displayDebug(app, 'Stopped sweep for piezo 1 amplitude');
                end      
            end
            % Phase 1
            if (app.ActivatePhase1Sweep.Value)    
                if (strcmp(get(app.sweepPhaseTimer1, 'Running'), 'off'))
                    app.sweep.phaseCount1 = 0;
                    app.sweepPhaseTimer1.Period = app.PhaseTimesteps1.Value;
                    start(app.sweepPhaseTimer1);
                    displayDebug(app, 'Started sweep for piezo 1 phase');
                end
            else
                if (strcmp(get(app.sweepPhaseTimer1, 'Running'), 'on'))
                    stop(app.sweepPhaseTimer1);
                    displayDebug(app, 'Stopped sweep for piezo 1 phase');
                end      
            end
            % Frequency 2
            if (app.ActivateFrequency2Sweep.Value)
                if (strcmp(get(app.sweepFreqTimer2, 'Running'), 'off'))
                    app.sweep.freqCount2 = 0;
                    app.sweepFreqTimer2.Period = app.FreqTimesteps2.Value;
                    start(app.sweepFreqTimer2);
                    displayDebug(app, 'Started sweep for piezo 2 frequency');
                end
            else
                if (strcmp(get(app.sweepFreqTimer2, 'Running'), 'on'))
                    stop(app.sweepFreqTimer2);
                    displayDebug(app, 'Stopped sweep for piezo 2 frequency');
                end
            end
            % Amplitude 2
            if (app.ActivateAmplitude2Sweep.Value)
                if (strcmp(get(app.sweepAmpTimer2, 'Running'), 'off'))
                    app.sweep.ampCount2 = 0;
                    app.sweepAmpTimer2.Period = app.AmplitudeTimesteps2.Value;
                    start(app.sweepAmpTimer2);
                    displayDebug(app, 'Started sweep for piezo 2 amplitude');
                end
            else
                if (strcmp(get(app.sweepAmpTimer2, 'Running'), 'on'))
                    stop(app.sweepAmpTimer2);
                    displayDebug(app, 'Stopped sweep for piezo 2 amplitude');
                end      
            end
            % Phase 2
            if (app.ActivatePhase2Sweep.Value)    
                if (strcmp(get(app.sweepPhaseTimer2, 'Running'), 'off'))
                    app.sweep.phaseCount2 = 0;
                    app.sweepPhaseTimer2.Period = app.PhaseTimesteps2.Value;
                    start(app.sweepPhaseTimer2);
                    displayDebug(app, 'Started sweep for piezo 2 phase');
                end
            else
                if (strcmp(get(app.sweepPhaseTimer2, 'Running'), 'on'))
                    stop(app.sweepPhaseTimer2);
                    displayDebug(app, 'Stopped sweep for piezo 2 phase');
                end      
            end
            
        end
        
        function runSweeps(app, ~, ~, varName)
            % Get relelvant variables given varName
            
            % Check if test is running, if not then don't start sweeps
            if ~(app.StartStopSwitch.Value == "Start")
                return;
    
            % Frequency 1
            elseif (strcmpi(varName, 'freq1'))
                minVal      = app.MinFreqHz1.Value;
                maxVal      = app.MaxFreqHz1.Value;
                step        = app.FreqStepHz1.Value;
                currCount   = app.sweep.freqCount1;
                currVal     = app.Frequency1.Value;
                % Increment value by step
                if (currCount == 0)
                    newVal = minVal;
                else
                    newVal = currVal + step;
                end
                % If reach limit then stop timer
                if (newVal > maxVal)
                    newVal = maxVal;
                    stop(app.sweepFreqTimer1);
                    displayDebug(app, 'Stopped sweep for piezo 1 frequency because limit reached');
                else
                    app.sweep.freqCount1 = currCount + 1;
                end
                app.Frequency1.Value = newVal;
                disp(newVal);
            % Amplitude 1
            elseif (strcmpi(varName, 'amp1'))
                minVal      = app.MinAmplitudeV1.Value;
                maxVal      = app.MaxAmplitudeV1.Value;
                step        = app.AmplitudeStepV1.Value;
                currCount   = app.sweep.ampCount1;
                currVal     = app.Amplitude1.Value;
                % Increment value by step
                if (currCount == 0)
                    newVal = minVal;
                else
                    newVal = currVal + step;
                end
                % If reach limit then stop timer
                if (newVal > maxVal)
                    newVal = maxVal;
                    stop(app.sweepAmpTimer1);
                    displayDebug(app, 'Stopped sweep for piezo 1 amplitude because limit reached');
                else
                    app.sweep.ampCount1 = currCount + 1;
                end
                app.Amplitude1.Value = newVal;
            % Phase 1
            elseif (strcmpi(varName, 'phase1'))
                minVal      = app.MinPhasedeg1.Value;
                maxVal      = app.MaxPhasedeg1.Value;
                step        = app.Phasestepdeg1.Value;
                currCount   = app.sweep.phaseCount1;
                currVal     = app.Phase1.Value;
                % Increment value by step
                if (currCount == 0)
                    newVal = minVal;
                else
                    newVal = currVal + step;
                end
                % If reach limit then stop timer
                if (newVal > maxVal)
                    newVal = maxVal;
                    stop(app.sweepPhaseTimer1);
                    displayDebug(app, 'Stopped sweep for piezo 1 phase because limit reached');
                else
                    app.sweep.phaseCount1 = currCount + 1;
                end
                app.Phase1.Value = newVal;
            % Frequency 2
            elseif (strcmpi(varName, 'freq2'))
                minVal      = app.MinFreqHz2.Value;
                maxVal      = app.MaxFreqHz2.Value;
                step        = app.FreqStepHz2.Value;
                currCount   = app.sweep.freqCount2;
                currVal     = app.Frequency2.Value;
                % Increment value by step
                if (currCount == 0)
                    newVal = minVal;
                else
                    newVal = currVal + step;
                end
                % If reach limit then stop timer
                if (newVal > maxVal)
                    newVal = maxVal;
                    stop(app.sweepFreqTimer2);
                    displayDebug(app, 'Stopped sweep for piezo 2 frequency because limit reached');
                else
                    app.sweep.freqCount2 = currCount + 1;
                end
                app.Frequency2.Value = newVal;
                disp(newVal);
            % Amplitude 2
            elseif (strcmpi(varName, 'amp2'))
                minVal      = app.MinAmplitudeV2.Value;
                maxVal      = app.MaxAmplitudeV2.Value;
                step        = app.AmplitudeStepV2.Value;
                currCount   = app.sweep.ampCount2;
                currVal     = app.Amplitude2.Value;
                % Increment value by step
                if (currCount == 0)
                    newVal = minVal;
                else
                    newVal = currVal + step;
                end
                % If reach limit then stop timer
                if (newVal > maxVal)
                    newVal = maxVal;
                    stop(app.sweepAmpTimer2);
                    displayDebug(app, 'Stopped sweep for piezo 2 amplitude because limit reached');
                else
                    app.sweep.ampCount2 = currCount + 1;
                end
                app.Amplitude2.Value = newVal;
            % Phase 2
            elseif (strcmpi(varName, 'phase2'))
                minVal      = app.MinPhasedeg2.Value;
                maxVal      = app.MaxPhasedeg2.Value;
                step        = app.Phasestepdeg2.Value;
                currCount   = app.sweep.phaseCount2;
                currVal     = app.Phase2.Value;
                % Increment value by step
                if (currCount == 0)
                    newVal = minVal;
                else
                    newVal = currVal + step;
                end
                % If reach limit then stop timer
                if (newVal > maxVal)
                    newVal = maxVal;
                    stop(app.sweepPhaseTimer2);
                    displayDebug(app, 'Stopped sweep for piezo 2 phase because limit reached');
                else
                    app.sweep.phaseCount2 = currCount + 1;
                end
                app.Phase2.Value = newVal;
            end
             
            % Send updated parameters to the teensy
            sendDataTeensy(app);
        end
        
        function blinkUpdate(app, ~, ~)
            % Check if test is running, if not then don't start sweeps
            if ~(app.StartStopSwitch.Value == "Start")
                return;
            else
                % Find how many sweeps are currently running
                listOfSweepingTimers = timerfindall('Tag', 'sweep');
                runningSweeps = 0;
                for i=1:length(listOfSweepingTimers)
                    if (strcmp(get(listOfSweepingTimers(i), 'Running'), 'on'))
                        runningSweeps = runningSweeps + 1;
                    end
                end
                % If there are sweeps running, blink the update panel, else
                % default color
                if (runningSweeps > 0)
                    % If button enabled, keep red
                    if (app.SendControlstoTeensyButton.Enable == 1)
                        app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
                        return;   
                    elseif (app.variables.blinkColor)
                        app.UpdateControlsPanel.BackgroundColor = app.variables.yellowColor;
                        app.variables.blinkColor = 0;
                    else
                        app.UpdateControlsPanel.BackgroundColor = app.variables.defaultBackgroundColor;
                        app.variables.blinkColor = 1;
                    end
                else
                    app.UpdateControlsPanel.BackgroundColor = app.variables.defaultBackgroundColor;
                end
            end
        end
        
        function stopBlinkUpdate(app, ~, ~)
            app.UpdateControlsPanel.BackgroundColor = app.variables.defaultBackgroundColor;
        end
        
        function StopTimer(app)
            listOfTimers = timerfindall;
            % Get handle to the one timer that we should have.
            if isempty(listOfTimers)
                % Exit if there is no timer to turn off.
                displayDebug(app, 'There are no timers in memory');
                return;
            end
            try
                % Stop all timers
                stop(listOfTimers);
                % Delete all timers from memory.
                if ~isempty(listOfTimers)
                    delete(listOfTimers(:));
                end
                displayDebug(app, 'Stopped and removed all remaining timer objects');
            catch ME
                displayDebug(app, ME.message);
            end
            return;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; close all;
            
            % Filename for log file
            app.debugFile.fileName                      = strjoin({pwd, '\logs\HX1_' datestr(now,'mm-dd-yy__HH_MM_SS') '.txt'}, '');
            app.iTextBox                                = 0;
            app.variables.blinkColor                    = 0;
            app.variables.connected                     = false;
            app.variables.firstData                     = true;
            app.variables.updateData                    = true;
            app.variables.dataJustSent                  = false;
            app.variables.countJustSent                 = 0;
            app.variables.endTesting                    = 1;
            app.variables.teensy_delimiter              = ',';
            app.variables.teensy_terminator             = '?';
            app.variables.lightRedColor                 = [0.95 0.39 0.39];
            app.variables.lightGreenColor               = [0.42 0.85 0.42];
            app.variables.darkRedColor                  = [1 0 0];
            app.variables.darkGreenColor                = [0 1 0];
            app.variables.yellowColor                   = [1 1 0];
            app.variables.defaultBackgroundColor        = [0.94 0.94 0.94];
            app.SendControlstoTeensyButton.Enable       = 0;
            app.sweep = struct('freqCount1', 0, ...
                               'ampCount1', 0, ...
                               'phaseCount1', 0, ...
                               'freqCount2', 0, ...
                               'ampCount2', 0, ...
                               'phaseCount2', 0);
            
            % Set panel background colors
            app.TeensyConnectionSettingsPanel.BackgroundColor   = app.variables.lightRedColor;
            app.SwitchBackgroundColor.BackgroundColor           = app.variables.lightRedColor;
            
            % Load the last variables saved in the configuration.mat file
            displayDebug(app, 'Loading configuration.mat');
            load configuration.mat BAUD comPort plotSeconds plotUpdateTime readVarCount readVarType sendVarCount sendVarType;
            % Save the variables from configuration.mat in the  
            % configuration property
            app.configuration.comPort           = comPort;
            app.configuration.BAUD              = BAUD;
            app.configuration.readVarCount      = readVarCount;
            app.configuration.sendVarCount      = sendVarCount;
            app.configuration.readVarType       = readVarType;
            app.configuration.sendVarType       = sendVarType;
            app.configuration.plotSeconds       = plotSeconds;        %seconds
            app.configuration.plotUpdateTime    = plotUpdateTime;     %seconds
            displayDebug(app, 'Loaded configuration.mat')
            
            % Load PID values from PIDvalues.mat
            load PIDvalues.mat Ki Kd Kp;
            app.configuration.Kp                = Kp;
            app.configuration.Ki                = Ki;
            app.configuration.Kd                = Kd;
            app.variables.Kp                    = Kp;
            app.variables.Ki                    = Ki;
            app.variables.Kd                    = Kd;
            %displayDebug(app, 'Loaded PIDvalues.mat');
            %displayDebug(app, fprintf('Kp: %.2f, Ki: %.2f, Kd %.2f', Kp, Ki, Kd));
            
            % Stop and delete all current timers
            displayDebug(app, 'Stopping all timers');
            StopTimer(app);
            
            % Create timer for plot update
            app.plotTimer = timer;
            app.plotTimer.ExecutionMode     = 'fixedRate';
            app.plotTimer.Period            = plotUpdateTime;
            app.plotTimer.BusyMode          = 'queue';
            app.plotTimer.TimerFcn          = @app.updateVisuals;
            
            % Sweep timers
            app.sweepFreqTimer1                     = timer('Tag', 'sweep');
            app.sweepFreqTimer1.ExecutionMode       = 'fixedRate';
            app.sweepFreqTimer1.BusyMode            = 'queue';
            app.sweepFreqTimer1.TimerFcn            = {@app.runSweeps, 'freq1'};
            
            app.sweepAmpTimer1                      = timer('Tag', 'sweep');
            app.sweepAmpTimer1.ExecutionMode        = 'fixedRate';
            app.sweepAmpTimer1.BusyMode             = 'queue';
            app.sweepAmpTimer1.TimerFcn             = {@app.runSweeps, 'amp1'};
            
            app.sweepPhaseTimer1                    = timer('Tag', 'sweep');
            app.sweepPhaseTimer1.ExecutionMode      = 'fixedRate';
            app.sweepPhaseTimer1.BusyMode           = 'queue';
            app.sweepPhaseTimer1.TimerFcn           = {@app.runSweeps, 'phase1'};
            
            app.sweepFreqTimer2                     = timer('Tag', 'sweep');
            app.sweepFreqTimer2.ExecutionMode       = 'fixedRate';
            app.sweepFreqTimer2.BusyMode            = 'queue';
            app.sweepFreqTimer2.TimerFcn            = {@app.runSweeps, 'freq2'};
            
            app.sweepAmpTimer2                      = timer('Tag', 'sweep');
            app.sweepAmpTimer2.ExecutionMode        = 'fixedRate';
            app.sweepAmpTimer2.BusyMode             = 'queue';
            app.sweepAmpTimer2.TimerFcn             = {@app.runSweeps, 'amp2'};
            
            app.sweepPhaseTimer2                    = timer('Tag', 'sweep');
            app.sweepPhaseTimer2.ExecutionMode      = 'fixedRate';
            app.sweepPhaseTimer2.BusyMode           = 'queue';
            app.sweepPhaseTimer2.TimerFcn           = {@app.runSweeps, 'phase2'};
            
            app.sweepBlinkColor                     = timer;
            app.sweepBlinkColor.ExecutionMode       = 'fixedRate';
            app.sweepBlinkColor.Period              = 1;
            app.sweepBlinkColor.BusyMode            = 'queue';
            app.sweepBlinkColor.TimerFcn            = @app.blinkUpdate;
            app.sweepBlinkColor.StopFcn             = @app.stopBlinkUpdate;
            start(app.sweepBlinkColor);
            
            % Initialize the visuals
            initVisuals(app);
            
            % Get serial port lists
            getSerialPortList(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop plot timer if running
            try
                if strcmp(app.plotTimer.Running, 'on')
                    stop(app.plotTimer);
                    % Send endTesting to teensy
                    app.variables.endTesting = 1;
                    sendDataTeensy(app);
                    % Save the current test if running
                    displayDebug(app, 'Stopping current test');     % Stop the test
                    saveData(app);                                  % Saves data to file
                end
                delete(app.plotTimer);      % Delete timer object
            catch
                displayDebug(app, 'Error while stopping plot timer');
                delete(app.plotTimer)
            end
            
            % Stop and delete all other timers
            StopTimer(app);           
                 
            % Save configuration.mat file
            try
                comPort         = app.configuration.comPort;
                BAUD            = app.configuration.BAUD;
                readVarCount    = app.configuration.readVarCount;
                sendVarCount    = app.configuration.sendVarCount;
                readVarType     = app.configuration.readVarType;
                sendVarType     = app.configuration.sendVarType;
                plotSeconds     = app.configuration.plotSeconds;
                plotUpdateTime  = app.configuration.plotUpdateTime; 
                save('configuration.mat', 'BAUD', 'comPort', 'plotSeconds', 'plotUpdateTime', 'readVarCount', ...
                'readVarType', 'sendVarCount', 'sendVarType', '-v7.3');
                displayDebug(app, 'Saved COM values to configuration.mat');
            catch
            end
            
            % Save the log file
            try
                logSave = fopen(app.debugFile.fileName, 'w');
                for i=1:length(app.debugFile.text)
                    fprintf(logSave,'%s\n',char(app.debugFile.text(i)));
                end
                fclose(logSave);
            catch
               for i=1:length(app.debugFile.text)
                    fprints('%s\n',char(app.debugFile.text(i)));
               end
            end
            
            
            % Close app
            delete(app)            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Check if the variables are valid
            comPort = app.COMPortDropDown.Value;
            baudRate = app.BaudrateDropDown.Value;
            goodComPort = false;
            goodBaudRate = false;

            if (any(strcmp(app.configuration.comList, comPort)))
                goodComPort = true;
            end
            if ~(strcmp("Select", baudRate))
                goodBaudRate = true;
                baudRate = str2num(baudRate); %#ok<ST2NM>
            end

            % Send data to the main app if good
            if (goodComPort && goodBaudRate)
                message = strjoin({'using' comPort '@' int2str(baudRate)});
                displayDebug(app, message);
                app.configuration.comPort   = comPort;
                app.configuration.BAUD      = baudRate;
                try
                    initTeensyConnection(app);
                    app.TeensyConnectionSettingsPanel.BackgroundColor = app.variables.lightGreenColor;
                    app.ConnectButton.Enable = 0;           % Disable connect button
                    app.RefreshButton.Enable = 0;           % Disable refresh button
                    app.COMPortDropDown.Enable = 0;         % Disable com port dropdown
                    app.BaudrateDropDown.Enable = 0;        % Disable baudrate dropdown
                    app.DefaultConnectSettings.Enable = 0;  % Disable default connect settings checkbox
                    app.variables.connected = true;
                catch
                    displayDebug(app, 'Fatal error with Teensy init');
                    app.TeensyConnectionSettingsPanel.BackgroundColor = app.variables.lightRedColor;
                end
            else
                displayDebug(app, 'Connection settings illegal');                
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.DefaultConnectSettings.Value = 0;
            getSerialPortList(app);
        end

        % Value changed function: DefaultConnectSettings
        function DefaultConnectSettingsValueChanged(app, event)
            value = app.DefaultConnectSettings.Value;
            if value == 1
                % Check if the default COM port is in current list, if not dispaly error
                comPort = app.configuration.comPort;
                if ~(any(strcmp(app.configuration.comList, comPort)))
                    displayDebug(app, 'Default COM port is not available');
                    app.DefaultConnectSettings.Value = 0;
                else
                    app.BaudrateDropDown.Value    = num2str(app.configuration.BAUD);
                    app.COMPortDropDown.Value     = app.configuration.comPort;
                    displayDebug(app, 'Valid default settings');
                end
            else
                getSerialPortList(app);
            end
        end

        % Value changed function: StartStopSwitch
        function StartStopSwitchValueChanged(app, event)
            value = app.StartStopSwitch.Value;
            if (value == "Start")
                % First check if connected
                if (app.variables.connected)
                    % Create file name for test
                    app.variables.fileName      = strjoin({pwd, '\data\HX1_' datestr(now,'mm-dd-yy__HH_MM_SS') '.csv'}, '');
                    app.variables.fileNameMat   = strjoin({pwd, '\data\HX1_' datestr(now,'mm-dd-yy__HH_MM_SS') '.mat'}, '');
                    % Initiate visuals again
                    initVisuals(app);          
                    % Start the timer for plot update
                    if strcmp(app.plotTimer.Running, 'off')
                        start(app.plotTimer);
                    end
                    % Update endTesting var on teensy
                    app.variables.endTesting = 0;
                    app.variables.Kp = app.configuration.Kp;
                    app.variables.Ki = app.configuration.Ki;
                    app.variables.Kd = app.configuration.Kd;
                    sendDataTeensy(app);
                    % Start the data aquisistion
                    displayDebug(app, 'Starting data logging');
                    app.SwitchBackgroundColor.BackgroundColor = app.variables.lightGreenColor;
            else
                    displayDebug(app, 'Not connected to Teensy yet');
                    app.StartStopSwitch.Value = "Stop & Save";
                end
            else
                app.SwitchBackgroundColor.BackgroundColor = app.variables.lightRedColor;
                % If plot timer running, stop it
                if strcmp(app.plotTimer.Running, 'on')
                    stop(app.plotTimer);
                end
                % Pause the sweeping timers
                listOfSweepTimers = timerfindall('Tag', 'sweep');
                for i=1:length(listOfSweepTimers)
                    try
                        stop(listOfSweepTimers(i))
                    catch
                    end
                end
                app.variables.endTesting = 1;
                sendDataTeensy(app);                            % Tell teensy test is ended
                displayDebug(app, 'Stopping current test');     % Stop the test
                saveData(app);                                  % Saves data to file
            end
            
        end

        % Value changed function: HeatFlux
        function HeatFluxValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: InletFluidTemp
        function InletFluidTempValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: HeaterModulesSwitch
        function HeaterModulesSwitchValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: RopeHeaterSwitch
        function RopeHeaterSwitchValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Enable2
        function Enable2ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Frequency2
        function Frequency2ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Amplitude2
        function Amplitude2ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Phase2
        function Phase2ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Frequency1
        function Frequency1ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Amplitude1
        function Amplitude1ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Phase1
        function Phase1ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Enable1
        function Enable1ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor; 
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Button pushed function: SendControlstoTeensyButton
        function SendControlstoTeensyButtonPushed(app, event)
            sendDataTeensy(app);
            displayDebug(app, 'Sent data to teensy');
            % Check if activate check boxes are clicked
            if (app.ActivateAmplitude1Sweep.Value==1 || app.ActivateFrequency1Sweep.Value==1 || app.ActivatePhase1Sweep.Value==1 || app.ActivateAmplitude2Sweep.Value==1 || app.ActivateFrequency2Sweep.Value==1 || app.ActivatePhase2Sweep.Value==1)
                initSweeps(app);
            else
                app.UpdateControlsPanel.BackgroundColor = app.variables.defaultBackgroundColor; 
            end
            app.SendControlstoTeensyButton.Enable = 0;
            app.variables.dataJustSent = 1;
        end

        % Value changed function: ActivateFrequency1Sweep
        function ActivateFrequency1SweepValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor; 
            app.SendControlstoTeensyButton.Enable = 1;
            app.sweep.freqCount1 = 0;
        end

        % Value changed function: ActivateAmplitude1Sweep
        function ActivateAmplitude1SweepValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor; 
            app.SendControlstoTeensyButton.Enable = 1;
            app.sweep.ampCount1 = 0;
        end

        % Value changed function: ActivatePhase1Sweep
        function ActivatePhase1SweepValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor; 
            app.SendControlstoTeensyButton.Enable = 1;
            app.sweep.phaseCount1 = 0;
        end

        % Value changed function: ActivateFrequency2Sweep
        function ActivateFrequency2SweepValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor; 
            app.SendControlstoTeensyButton.Enable = 1;
            app.sweep.freqCount2 = 0;
        end

        % Value changed function: ActivateAmplitude2Sweep
        function ActivateAmplitude2SweepValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor; 
            app.SendControlstoTeensyButton.Enable = 1;
            app.sweep.ampCount2 = 0;
        end

        % Value changed function: ActivatePhase2Sweep
        function ActivatePhase2SweepValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor; 
            app.SendControlstoTeensyButton.Enable = 1;
            app.sweep.phaseCount2 = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {739, 739, 739};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {739, 739};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {277, '1x', 296};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.TeensyConnectionSettingsPanel = uipanel(app.GridLayout3);
            app.TeensyConnectionSettingsPanel.Title = 'Teensy Connection Settings';
            app.COMPortDropDownLabel = uilabel(app.TeensyConnectionSettingsPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.TeensyConnectionSettingsPanel);
            app.BaudrateDropDownLabel = uilabel(app.TeensyConnectionSettingsPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.TeensyConnectionSettingsPanel);
            app.DefaultConnectSettings = uicheckbox(app.TeensyConnectionSettingsPanel);
            app.DefaultConnectSettings.ValueChangedFcn = createCallbackFcn(app, @DefaultConnectSettingsValueChanged, true);
            app.DefaultConnectSettings.Text = 'Default connection settings';
            app.RefreshButton = uibutton(app.TeensyConnectionSettingsPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ConnectButton = uibutton(app.TeensyConnectionSettingsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.HeaterControlPanel = uipanel(app.GridLayout3);
            app.HeaterControlPanel.Title = 'Heater Control';
            app.HeatFluxWcm2EditFieldLabel = uilabel(app.HeaterControlPanel);
            app.HeatFluxWcm2EditFieldLabel.Text = 'Heat Flux (W/cm^2)';
            app.HeatFlux = uieditfield(app.HeaterControlPanel, 'numeric');
            app.HeatFlux.ValueChangedFcn = createCallbackFcn(app, @HeatFluxValueChanged, true);
            app.InletFluidTempCEditFieldLabel = uilabel(app.HeaterControlPanel);
            app.InletFluidTempCEditFieldLabel.Text = 'Inlet Fluid Temp (C)';
            app.InletFluidTemp = uieditfield(app.HeaterControlPanel, 'numeric');
            app.InletFluidTemp.ValueChangedFcn = createCallbackFcn(app, @InletFluidTempValueChanged, true);
            app.HeaterModulesSwitchLabel = uilabel(app.HeaterControlPanel);
            app.HeaterModulesSwitchLabel.Text = 'Heater Modules';
            app.HeaterModulesSwitch = uiswitch(app.HeaterControlPanel, 'slider');
            app.HeaterModulesSwitch.ValueChangedFcn = createCallbackFcn(app, @HeaterModulesSwitchValueChanged, true);
            app.RopeHeaterSwitchLabel = uilabel(app.HeaterControlPanel);
            app.RopeHeaterSwitchLabel.Text = 'Rope Heater';
            app.RopeHeaterSwitch = uiswitch(app.HeaterControlPanel, 'slider');
            app.RopeHeaterSwitch.ValueChangedFcn = createCallbackFcn(app, @RopeHeaterSwitchValueChanged, true);
            app.SwitchBackgroundColor = uiimage(app.GridLayout3);
            app.SwitchBackgroundColor.ImageSource = 'background.png';
            app.StartStopSwitch = uiswitch(app.GridLayout3, 'slider');
            app.StartStopSwitch.Items = {'Stop & Save', 'Start'};
            app.StartStopSwitch.ValueChangedFcn = createCallbackFcn(app, @StartStopSwitchValueChanged, true);
            app.StartStopSwitch.Value = 'Stop & Save';
            app.UpdateControlsPanel = uipanel(app.GridLayout3);
            app.UpdateControlsPanel.Title = 'Update Controls';
            app.SendControlstoTeensyButton = uibutton(app.UpdateControlsPanel, 'push');
            app.SendControlstoTeensyButton.ButtonPushedFcn = createCallbackFcn(app, @SendControlstoTeensyButtonPushed, true);
            app.SendControlstoTeensyButton.Text = {'Send Controls to Teensy'; ''};
            app.Piezo1TabGroup = uitabgroup(app.GridLayout3);
            app.Piezo1 = uitab(app.Piezo1TabGroup);
            app.Piezo1.Title = 'Piezo 1';
            app.Enable1 = uiswitch(app.Piezo1, 'slider');
            app.Enable1.ValueChangedFcn = createCallbackFcn(app, @Enable1ValueChanged, true);
            app.FrequencyHzEditFieldLabel_3 = uilabel(app.Piezo1);
            app.FrequencyHzEditFieldLabel_3.Text = 'Frequency (Hz)';
            app.Frequency1 = uieditfield(app.Piezo1, 'numeric');
            app.Frequency1.ValueChangedFcn = createCallbackFcn(app, @Frequency1ValueChanged, true);
            app.AmplitudePPVEditFieldLabel_3 = uilabel(app.Piezo1);
            app.AmplitudePPVEditFieldLabel_3.Text = 'Amplitude (P-P V)';
            app.Amplitude1 = uieditfield(app.Piezo1, 'numeric');
            app.Amplitude1.ValueChangedFcn = createCallbackFcn(app, @Amplitude1ValueChanged, true);
            app.PhasedegEditFieldLabel_3 = uilabel(app.Piezo1);
            app.PhasedegEditFieldLabel_3.Text = 'Phase (deg)';
            app.Phase1 = uieditfield(app.Piezo1, 'numeric');
            app.Phase1.ValueChangedFcn = createCallbackFcn(app, @Phase1ValueChanged, true);
            app.Piezo1FreqSweep = uitab(app.Piezo1TabGroup);
            app.Piezo1FreqSweep.Title = 'Freq.';
            app.TimestepsEditFieldLabel = uilabel(app.Piezo1FreqSweep);
            app.TimestepsEditFieldLabel.Text = 'Time step (s)';
            app.FreqTimesteps1 = uieditfield(app.Piezo1FreqSweep, 'numeric');
            app.FreqTimesteps1.Value = 0.1;
            app.ActivateFrequency1Sweep = uicheckbox(app.Piezo1FreqSweep);
            app.ActivateFrequency1Sweep.ValueChangedFcn = createCallbackFcn(app, @ActivateFrequency1SweepValueChanged, true);
            app.ActivateFrequency1Sweep.Text = 'Activate sweep';
            app.FreqstepHzLabel = uilabel(app.Piezo1FreqSweep);
            app.FreqstepHzLabel.Text = 'Freq. step (Hz)';
            app.FreqStepHz1 = uieditfield(app.Piezo1FreqSweep, 'numeric');
            app.FreqStepHz1.Value = 1;
            app.MinFreqHzLabel = uilabel(app.Piezo1FreqSweep);
            app.MinFreqHzLabel.Text = 'Min. Freq. (Hz)';
            app.MinFreqHz1 = uieditfield(app.Piezo1FreqSweep, 'numeric');
            app.MinFreqHz1.Value = 10;
            app.MaxFreqHzLabel = uilabel(app.Piezo1FreqSweep);
            app.MaxFreqHzLabel.Text = 'Max Freq. (Hz)';
            app.MaxFreqHz1 = uieditfield(app.Piezo1FreqSweep, 'numeric');
            app.MaxFreqHz1.Value = 1000;
            app.Piezo1AmpSweep = uitab(app.Piezo1TabGroup);
            app.Piezo1AmpSweep.Title = 'Amp.';
            app.ActivateAmplitude1Sweep = uicheckbox(app.Piezo1AmpSweep);
            app.ActivateAmplitude1Sweep.ValueChangedFcn = createCallbackFcn(app, @ActivateAmplitude1SweepValueChanged, true);
            app.ActivateAmplitude1Sweep.Text = 'Activate sweep';
            app.MinAmplitudeVEditFieldLabel = uilabel(app.Piezo1AmpSweep);
            app.MinAmplitudeVEditFieldLabel.Text = 'Min. Amplitude (V)';
            app.MinAmplitudeV1 = uieditfield(app.Piezo1AmpSweep, 'numeric');
            app.MaxAmplitudeVEditFieldLabel = uilabel(app.Piezo1AmpSweep);
            app.MaxAmplitudeVEditFieldLabel.Text = 'Max Amplitude (V)';
            app.MaxAmplitudeV1 = uieditfield(app.Piezo1AmpSweep, 'numeric');
            app.MaxAmplitudeV1.Value = 100;
            app.AmplitudestepVLabel_2 = uilabel(app.Piezo1AmpSweep);
            app.AmplitudestepVLabel_2.Text = 'Amplitude step (V)';
            app.AmplitudeStepV1 = uieditfield(app.Piezo1AmpSweep, 'numeric');
            app.AmplitudeStepV1.Value = 1;
            app.TimestepsLabel = uilabel(app.Piezo1AmpSweep);
            app.TimestepsLabel.Text = 'Time step (s)';
            app.AmplitudeTimesteps1 = uieditfield(app.Piezo1AmpSweep, 'numeric');
            app.AmplitudeTimesteps1.Value = 1;
            app.Piezo1PhaseSweep = uitab(app.Piezo1TabGroup);
            app.Piezo1PhaseSweep.Title = 'Phase';
            app.ActivatePhase1Sweep = uicheckbox(app.Piezo1PhaseSweep);
            app.ActivatePhase1Sweep.ValueChangedFcn = createCallbackFcn(app, @ActivatePhase1SweepValueChanged, true);
            app.ActivatePhase1Sweep.Text = 'Activate sweep';
            app.MinPhasedegEditFieldLabel = uilabel(app.Piezo1PhaseSweep);
            app.MinPhasedegEditFieldLabel.Text = 'Min. Phase (deg)';
            app.MinPhasedeg1 = uieditfield(app.Piezo1PhaseSweep, 'numeric');
            app.MaxPhasedegEditFieldLabel = uilabel(app.Piezo1PhaseSweep);
            app.MaxPhasedegEditFieldLabel.Text = 'Max Phase (deg)';
            app.MaxPhasedeg1 = uieditfield(app.Piezo1PhaseSweep, 'numeric');
            app.MaxPhasedeg1.Value = 180;
            app.PhasestepdegEditFieldLabel = uilabel(app.Piezo1PhaseSweep);
            app.PhasestepdegEditFieldLabel.Text = 'Phase step (deg)';
            app.Phasestepdeg1 = uieditfield(app.Piezo1PhaseSweep, 'numeric');
            app.Phasestepdeg1.Value = 1;
            app.TimestepsEditFieldLabel_2 = uilabel(app.Piezo1PhaseSweep);
            app.TimestepsEditFieldLabel_2.Text = 'Time step (s)';
            app.PhaseTimesteps1 = uieditfield(app.Piezo1PhaseSweep, 'numeric');
            app.PhaseTimesteps1.Value = 1;
            app.Piezo2TabGroup = uitabgroup(app.GridLayout3);
            app.Piezo2 = uitab(app.Piezo2TabGroup);
            app.Piezo2.Title = 'Piezo 2';
            app.Enable2 = uiswitch(app.Piezo2, 'slider');
            app.Enable2.ValueChangedFcn = createCallbackFcn(app, @Enable2ValueChanged, true);
            app.FrequencyHzEditFieldLabel_4 = uilabel(app.Piezo2);
            app.FrequencyHzEditFieldLabel_4.Text = 'Frequency (Hz)';
            app.Frequency2 = uieditfield(app.Piezo2, 'numeric');
            app.Frequency2.ValueChangedFcn = createCallbackFcn(app, @Frequency2ValueChanged, true);
            app.AmplitudePPVEditFieldLabel_4 = uilabel(app.Piezo2);
            app.AmplitudePPVEditFieldLabel_4.Text = 'Amplitude (P-P V)';
            app.Amplitude2 = uieditfield(app.Piezo2, 'numeric');
            app.Amplitude2.ValueChangedFcn = createCallbackFcn(app, @Amplitude2ValueChanged, true);
            app.PhasedegEditFieldLabel_4 = uilabel(app.Piezo2);
            app.PhasedegEditFieldLabel_4.Text = 'Phase (deg)';
            app.Phase2 = uieditfield(app.Piezo2, 'numeric');
            app.Phase2.ValueChangedFcn = createCallbackFcn(app, @Phase2ValueChanged, true);
            app.Piezo2FreqSweep = uitab(app.Piezo2TabGroup);
            app.Piezo2FreqSweep.Title = 'Freq.';
            app.ActivateFrequency2Sweep = uicheckbox(app.Piezo2FreqSweep);
            app.ActivateFrequency2Sweep.ValueChangedFcn = createCallbackFcn(app, @ActivateFrequency2SweepValueChanged, true);
            app.ActivateFrequency2Sweep.Text = 'Activate sweep';
            app.TimestepsEditField_2Label = uilabel(app.Piezo2FreqSweep);
            app.TimestepsEditField_2Label.Text = 'Time step (s)';
            app.FreqTimesteps2 = uieditfield(app.Piezo2FreqSweep, 'numeric');
            app.FreqTimesteps2.Value = 0.1;
            app.FreqstepHzLabel_2 = uilabel(app.Piezo2FreqSweep);
            app.FreqstepHzLabel_2.Text = 'Freq. step (Hz)';
            app.FreqStepHz2 = uieditfield(app.Piezo2FreqSweep, 'numeric');
            app.FreqStepHz2.Value = 1;
            app.MinFreqHzEditField_2Label = uilabel(app.Piezo2FreqSweep);
            app.MinFreqHzEditField_2Label.Text = 'Min. Freq. (Hz)';
            app.MinFreqHz2 = uieditfield(app.Piezo2FreqSweep, 'numeric');
            app.MinFreqHz2.Value = 10;
            app.MaxFreqHzEditField_2Label = uilabel(app.Piezo2FreqSweep);
            app.MaxFreqHzEditField_2Label.Text = 'Max Freq. (Hz)';
            app.MaxFreqHz2 = uieditfield(app.Piezo2FreqSweep, 'numeric');
            app.MaxFreqHz2.Value = 1000;
            app.Piezo2AmpSweep = uitab(app.Piezo2TabGroup);
            app.Piezo2AmpSweep.Title = 'Amp.';
            app.ActivateAmplitude2Sweep = uicheckbox(app.Piezo2AmpSweep);
            app.ActivateAmplitude2Sweep.ValueChangedFcn = createCallbackFcn(app, @ActivateAmplitude2SweepValueChanged, true);
            app.ActivateAmplitude2Sweep.Text = 'Activate sweep';
            app.MinAmplitudeVEditFieldLabel_2 = uilabel(app.Piezo2AmpSweep);
            app.MinAmplitudeVEditFieldLabel_2.Text = 'Min. Amplitude (V)';
            app.MinAmplitudeV2 = uieditfield(app.Piezo2AmpSweep, 'numeric');
            app.MaxAmplitudeVEditFieldLabel_2 = uilabel(app.Piezo2AmpSweep);
            app.MaxAmplitudeVEditFieldLabel_2.Text = 'Max Amplitude (V)';
            app.MaxAmplitudeV2 = uieditfield(app.Piezo2AmpSweep, 'numeric');
            app.MaxAmplitudeV2.Value = 100;
            app.AmplitudestepVLabel = uilabel(app.Piezo2AmpSweep);
            app.AmplitudestepVLabel.Text = 'Amplitude step (V)';
            app.AmplitudeStepV2 = uieditfield(app.Piezo2AmpSweep, 'numeric');
            app.AmplitudeStepV2.Value = 1;
            app.TimestepsLabel_2 = uilabel(app.Piezo2AmpSweep);
            app.TimestepsLabel_2.Text = 'Time step (s)';
            app.AmplitudeTimesteps2 = uieditfield(app.Piezo2AmpSweep, 'numeric');
            app.AmplitudeTimesteps2.Value = 1;
            app.Piezo2PhaseSweep = uitab(app.Piezo2TabGroup);
            app.Piezo2PhaseSweep.Title = 'Phase';
            app.ActivatePhase2Sweep = uicheckbox(app.Piezo2PhaseSweep);
            app.ActivatePhase2Sweep.ValueChangedFcn = createCallbackFcn(app, @ActivatePhase2SweepValueChanged, true);
            app.ActivatePhase2Sweep.Text = 'Activate sweep';
            app.MinPhasedegEditFieldLabel_2 = uilabel(app.Piezo2PhaseSweep);
            app.MinPhasedegEditFieldLabel_2.Text = 'Min. Phase (deg)';
            app.MinPhasedeg2 = uieditfield(app.Piezo2PhaseSweep, 'numeric');
            app.MaxPhasedegEditFieldLabel_2 = uilabel(app.Piezo2PhaseSweep);
            app.MaxPhasedegEditFieldLabel_2.Text = 'Max Phase (deg)';
            app.MaxPhasedeg2 = uieditfield(app.Piezo2PhaseSweep, 'numeric');
            app.MaxPhasedeg2.Value = 180;
            app.PhasestepdegEditFieldLabel_2 = uilabel(app.Piezo2PhaseSweep);
            app.PhasestepdegEditFieldLabel_2.Text = 'Phase step (deg)';
            app.Phasestepdeg2 = uieditfield(app.Piezo2PhaseSweep, 'numeric');
            app.Phasestepdeg2.Value = 1;
            app.TimestepsEditFieldLabel_3 = uilabel(app.Piezo2PhaseSweep);
            app.TimestepsEditFieldLabel_3.Text = 'Time step (s)';
            app.PhaseTimesteps2 = uieditfield(app.Piezo2PhaseSweep, 'numeric');
            app.PhaseTimesteps2.Value = 1;
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.outletPressurePlot = uiaxes(app.GridLayout2);
            title(app.outletPressurePlot, 'Outlet Pressure')
            xlabel(app.outletPressurePlot, 'Time (s)')
            ylabel(app.outletPressurePlot, 'Pressure (psia)')
            app.flowRatePlot = uiaxes(app.GridLayout2);
            title(app.flowRatePlot, 'Flow Rate')
            xlabel(app.flowRatePlot, 'Time (s)')
            ylabel(app.flowRatePlot, 'Flow Rate (mL/min)')
            zlabel(app.flowRatePlot, 'Z')
            app.boilSurfacePlot = uiaxes(app.GridLayout2);
            title(app.boilSurfacePlot, 'Boil Surface Temperature')
            xlabel(app.boilSurfacePlot, 'Time (s)')
            ylabel(app.boilSurfacePlot, 'Temperature (C)')
            zlabel(app.boilSurfacePlot, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.InletFluidTempCPanel = uipanel(app.GridLayout4);
            app.InletFluidTempCPanel.Title = 'Inlet Fluid Temp (C)';
            app.InletTempGage = uigauge(app.InletFluidTempCPanel, 'circular');
            app.InletTempGage.Limits = [30 70];
            app.InletTemp = uilabel(app.InletFluidTempCPanel);
            app.InletTemp.Text = {'TEMP (C)'; ''};
            app.DebugWindowPanel = uipanel(app.GridLayout4);
            app.DebugWindowPanel.Title = 'Debug Window';
            app.DebugTextArea = uitextarea(app.DebugWindowPanel);
            app.DebugTextArea.Editable = 'off';
            app.AverageHeaterModuleTempCPanel = uipanel(app.GridLayout4);
            app.AverageHeaterModuleTempCPanel.Title = 'Average Heater Module Temp (C)';
            app.avgHeaterTempGage = uigauge(app.AverageHeaterModuleTempCPanel, 'circular');
            app.avgHeaterTempGage.Limits = [20 300];
            app.avgHeaterTemp = uilabel(app.AverageHeaterModuleTempCPanel);
            app.avgHeaterTemp.Text = {'TEMP (C)'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HXApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end