classdef PIDTune < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        UpdateControlsPanel            matlab.ui.container.Panel
        SendControlstoTeensyButton     matlab.ui.control.Button
        StartStopSwitch                matlab.ui.control.Switch
        PIDTunePanel                   matlab.ui.container.Panel
        KdDefinition                   matlab.ui.control.Label
        KiDefinition                   matlab.ui.control.Label
        KpDefinition                   matlab.ui.control.Label
        KdBox                          matlab.ui.control.NumericEditField
        KdLabel                        matlab.ui.control.Label
        KiBox                          matlab.ui.control.NumericEditField
        KiLabel_2                      matlab.ui.control.Label
        KpBox                          matlab.ui.control.NumericEditField
        KpLabel                        matlab.ui.control.Label
        SwitchBackgroundColor          matlab.ui.control.Image
        HeaterControlPanel             matlab.ui.container.Panel
        RopeHeaterSwitch               matlab.ui.control.Switch
        RopeHeaterSwitchLabel          matlab.ui.control.Label
        targetInletFluidTemp           matlab.ui.control.NumericEditField
        InletFluidTempCEditFieldLabel  matlab.ui.control.Label
        TeensyConnectionSettingsPanel  matlab.ui.container.Panel
        ConnectButton                  matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        DefaultConnectSettings         matlab.ui.control.CheckBox
        BaudrateDropDown               matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        COMPortDropDown                matlab.ui.control.DropDown
        COMPortDropDownLabel           matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        inletFluidTempPlot             matlab.ui.control.UIAxes
        flowRatePlot                   matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        DebugWindowPanel               matlab.ui.container.Panel
        DebugTextArea                  matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        iTextBox        % Line in debug textbox
        configuration   % Variables relating to current configuration
        variables       % Misc variables
        teensy          % Teensy object
        debugFile       % Debug file
        plotTimer       % Timer object for updating plots/visuals
        inletFluidTempActualLineObject              % Line object for actual fluid temp
        inletFluidTempTargetLineObject              % Line object for target fluid temp
        flowRateLineObject                  % Line object for flowRatePlot
        
    end
    
    methods (Access = private)
             
        function getSerialPortList(app)
            displayDebug(app, 'Fetching COM settings');
            serialPorts = serialportlist("available");
            list = ["Select"]; %#ok<NBRAK> 
            for i=1:length(serialPorts)
                list(end+1) = serialPorts(i); %#ok<AGROW> 
            end
            app.configuration.comList       = list;
            app.configuration.baudList      = ["Select", "1200", "2400", "4800", "9600", "19200", "38400", "57600", "115200"];
            app.BaudrateDropDown.Items      = app.configuration.baudList;
            app.COMPortDropDown.Items       = app.configuration.comList;
            app.BaudrateDropDown.Value      = "Select";
            app.COMPortDropDown.Value       = "Select";
            displayDebug(app, 'Fetched COM settings');
        end
        
        function displayDebug(app, message)
            fullMessage = strjoin({datestr(now,'HH:MM:SS.FFF') ':' message});
            % Display to the dialogue box in the app
            app.iTextBox = app.iTextBox + 1;
            app.DebugTextArea.Value(app.iTextBox) = {fullMessage};
            % Save to debugFile property for final debug file
            app.debugFile.text(app.iTextBox) = {fullMessage};
        end
        
        function initTeensyConnection(app)
            displayDebug(app, 'Starting Teensy connection');
            app.teensy = serialport(app.configuration.comPort, app.configuration.BAUD);
            configureTerminator(app.teensy, "CR/LF");
            configureCallback(app.teensy, "terminator", @app.runDataAquisition)
            flush(app.teensy);
            displayDebug(app, 'Successful connection to Teensy');
            app.COMPortDropDown.Value   = app.configuration.comPort;
            app.BaudrateDropDown.Value  = num2str(app.configuration.BAUD);
            initDataAquisition(app);
        end
        
        function initDataAquisition(app)
            app.teensy.UserData = struct("time",[], ...
                                         "inletPressureUpstream",[], ...
                                         "inletPressureDownstream",[], ...
                                         "outletPressureVapor",[], ...
                                         "outletPressureLiquid",[], ...
                                         "heatFlux",[], ...
                                         "heaterTemperature1",[], ...
                                         "heaterTemperature2",[], ...
                                         "heaterTemperature3",[], ...
                                         "heaterTemperature4",[], ...
                                         "heaterTemperature5",[], ...
                                         "boilSurfaceTemperature1",[], ...
                                         "boilSurfaceTemperature2",[], ...
                                         "boilSurfaceTemperature3",[], ...
                                         "boilSurfaceTemperature4",[], ...
                                         "averageBoilSurfaceTemp",[], ...
                                         "inletFlowRate",[], ...
                                         "inletFluidTemperature",[], ...
                                         "targetFluidTemperature",[], ...
                                         "frequency1",[], ...
                                         "frequency2",[], ...
                                         "amplitude1",[], ...
                                         "amplitude2",[], ...
                                         "phase1",[], ...
                                         "phase2",[], ...
                                         "enable1",[], ...
                                         "enable2",[], ...
                                         "Kp",[], ...
                                         "Kd",[], ...
                                         "Ki",[], ...
                                         "endTesting", []);
        end

        function saveData(app)
            displayDebug(app, strjoin({'Saving data to' app.variables.fileName}));
            s = app.teensy.UserData;
            FID = fopen(app.variables.fileName,'w');    % open file at the filename
            headers = fieldnames(s);                    % Get the fieldnames in struct
            m = length(headers);
            sz = zeros(m,2);
            t = length(s);
            % Modify mx0 to 0xm data
            for q=1:m
               s.(headers{q}) = s.(headers{q})'; 
            end
            
            for rr = 1:t
                l = '';
                for ii = 1:m
                    sz(ii,:) = size(s(rr).(headers{ii}));   
                    if ischar(s(rr).(headers{ii}))
                        sz(ii,2) = 1;
                    end
                    l = [l,'"',headers{ii},'",',repmat(',',1,sz(ii,2)-1)];
                end
                l = [l,'\n'];
                fprintf(FID,l);
                n = max(sz(:,1));
                for ii = 1:n
                    l = '';
                    for jj = 1:m
                        c = s(rr).(headers{jj});
                        str = '';
                        
                        if sz(jj,1)<ii
                            str = repmat(',',1,sz(jj,2));
                        else
                            if isnumeric(c)
                                for kk = 1:sz(jj,2)
                                    str = [str,num2str(c(ii,kk)),','];
                                end
                            elseif islogical(c)
                                for kk = 1:sz(jj,2)
                                    str = [str,num2str(double(c(ii,kk))),','];
                                end
                            elseif ischar(c)
                                str = ['"',c(ii,:),'",'];
                            elseif iscell(c)
                                if isnumeric(c{1,1})
                                    for kk = 1:sz(jj,2)
                                        str = [str,num2str(c{ii,kk}),','];
                                    end
                                elseif islogical(c{1,1})
                                    for kk = 1:sz(jj,2)
                                        str = [str,num2str(double(c{ii,kk})),','];
                                    end
                                elseif ischar(c{1,1})
                                    for kk = 1:sz(jj,2)
                                        str = [str,'"',c{ii,kk},'",'];
                                    end
                                end
                            end
                        end
                        l = [l,str];
                    end
                    l = [l,'\n'];
                    fprintf(FID,l);
                end
                fprintf(FID,'\n');
            end
            fclose(FID);
            initDataAquisition(app);                        % Re-Initiate the data struct (clear the data)
            displayDebug(app, 'Reset data struct');
        end

        function sendDataTeensy(app)
            % Get current values for vars to send
            app.variables.Kd                = app.KdBox.Value;
            app.variables.Ki                = app.KiBox.Value;
            app.variables.Kp                = app.KpBox.Value;
            app.variables.enable1           = 0;
            app.variables.enable2           = 0;
            app.variables.frequency1        = 0;
            app.variables.amplitude1        = 0;
            app.variables.phase1            = 0;
            app.variables.frequency2        = 0;
            app.variables.amplitude2        = 0;
            app.variables.phase2            = 0;
            app.variables.heatFlux          = 0;
            app.variables.targetFluidTemp   = app.targetInletFluidTemp.Value;
            app.variables.enableHeaters     = 0;    % make sure the heaters are off

            if (app.RopeHeaterSwitch.Value == "On")
                app.variables.enableRopeHeater = 1;
            else
                app.variables.enableRopeHeater = 0;
            end
            % Create list of variables to send to Teensy
            sendList         = [app.variables.enable1, ...
                                app.variables.frequency1, ...
                                app.variables.amplitude1, ...
                                app.variables.phase1, ...
                                app.variables.enable2, ...
                                app.variables.frequency2, ...
                                app.variables.amplitude2, ...
                                app.variables.phase2, ...
                                app.variables.heatFlux, ...
                                app.variables.targetFluidTemp, ...
                                app.variables.enableHeaters, ...
                                app.variables.enableRopeHeater, ...
                                app.variables.Kp, ...
                                app.variables.Kd, ...
                                app.variables.Ki, ...
                                app.variables.endTesting];
            % Construct string to send via serial
            sendString = append(strjoin(string(sendList), app.variables.teensy_delimiter), app.variables.teensy_terminator);
            writeline(app.teensy, sendString);       % Send string via serial
            displayDebug(app, 'Sent data to teensy');
        end

        function runDataAquisition(app,~,~)
            % Check if the Start switch is activated, if so then aquire
            % data
            if (app.StartStopSwitch.Value == "Start" || app.variables.firstData)
                data = str2double(split(readline(app.teensy), ","));
                i = 1;
                app.teensy.UserData.time(end+1)                     = data(i); i=i+1;
                app.teensy.UserData.inletPressureUpstream(end+1)    = data(i); i=i+1;
                app.teensy.UserData.inletPressureDownstream(end+1)  = data(i); i=i+1;
                app.teensy.UserData.outletPressureVapor(end+1)      = data(i); i=i+1;
                app.teensy.UserData.outletPressureLiquid(end+1)     = data(i); i=i+1;
                app.teensy.UserData.heatFlux(end+1)                 = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature1(end+1)       = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature2(end+1)       = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature3(end+1)       = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature4(end+1)       = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature5(end+1)       = data(i); i=i+1;
                app.teensy.UserData.boilSurfaceTemperature1(end+1)  = data(i); i=i+1;
                app.teensy.UserData.boilSurfaceTemperature2(end+1)  = data(i); i=i+1;
                app.teensy.UserData.boilSurfaceTemperature3(end+1)  = data(i); i=i+1;
                app.teensy.UserData.boilSurfaceTemperature4(end+1)  = data(i); i=i+1;
                app.teensy.UserData.averageBoilSurfaceTemp(end+1)   = data(i); i=i+1;
                app.teensy.UserData.inletFlowRate(end+1)            = data(i); i=i+1;
                app.teensy.UserData.inletFluidTemperature(end+1)    = data(i); i=i+1;
                app.teensy.UserData.targetFluidTemperature(end+1)   = data(i); i=i+1;
                app.teensy.UserData.frequency1(end+1)               = data(i); i=i+1;
                app.teensy.UserData.frequency2(end+1)               = data(i); i=i+1;
                app.teensy.UserData.amplitude1(end+1)               = data(i); i=i+1;
                app.teensy.UserData.amplitude2(end+1)               = data(i); i=i+1;
                app.teensy.UserData.phase1(end+1)                   = data(i); i=i+1;
                app.teensy.UserData.phase2(end+1)                   = data(i); i=i+1;
                app.teensy.UserData.enable1(end+1)                  = data(i); i=i+1;
                app.teensy.UserData.enable2(end+1)                  = data(i); i=i+1;
                app.teensy.UserData.Kp(end+1)                       = data(i); i=i+1;
                app.teensy.UserData.Kd(end+1)                       = data(i); i=i+1;
                app.teensy.UserData.Ki(end+1)                       = data(i);
                app.teensy.UserData.endTesting(end+1)               = app.variables.endTesting;
                
                t = app.teensy.UserData.time(end) - app.teensy.UserData.time(1);
                
                % Add points to flow rate plot
                addpoints(app.flowRateLineObject, t, app.teensy.UserData.inletFlowRate(end));
                
                % Add points to boil surface plot
                addpoints(app.inletFluidTempTargetLineObject, t, app.teensy.UserData.targetFluidTemperature(end));
                addpoints(app.inletFluidTempActualLineObject, t, app.teensy.UserData.inletFluidTemperature(end));
                   
                % Save Kd, Kp, Ki to variables property
                app.variables.Kp = app.teensy.UserData.Kp(end);
                app.variables.Ki = app.teensy.UserData.Ki(end);
                app.variables.Kd = app.teensy.UserData.Kd(end);
                
                % If first data, update values on UI
                if (app.variables.firstData || app.variables.updateData)
                   
                    if (app.teensy.UserData.targetFluidTemperature(end) > 0)
                        app.RopeHeaterSwitch.Value      = "On";
                    else
                        app.RopeHeaterSwitch.Value      = "Off";
                    end
                    app.KpBox.Value                 = app.teensy.UserData.Kp(end);
                    app.KiBox.Value                 = app.teensy.UserData.Ki(end);
                    app.KdBox.Value                 = app.teensy.UserData.Kd(end);
                    app.targetInletFluidTemp.Value        = app.teensy.UserData.targetFluidTemperature(end);
                    app.RopeHeaterSwitch.Value      = "On";
                    app.variables.firstData = false;
                    app.variables.updateData = false;
                end
            end
        end
        
        function initVisuals(app)
            % Clear the plots and points
            try
                clearpoints(app.inletFluidTempActualLineObject);
                clearpoints(app.inletFluidTempTargetLineObject);
                clearpoints(app.flowRateLineObject);
                cla(app.inletFluidTempPlot);
                cla(app.flowRatePlot);
            catch
            end
            
            % Inlet fluid temp plot
            app.inletFluidTempPlot.XLimMode         = 'manual';
            app.inletFluidTempPlot.YLimMode         = 'auto';
            app.inletFluidTempPlot.XLim             = [0 app.configuration.plotSeconds];
            app.inletFluidTempActualLineObject     = animatedline(app.inletFluidTempPlot,"Color", 'r',"LineStyle","-", "DisplayName","Actual");
            app.inletFluidTempTargetLineObject     = animatedline(app.inletFluidTempPlot,"Color", 'b',"LineStyle","--", "DisplayName","Target");
            legend(app.inletFluidTempPlot,'Location', 'northwest', 'Orientation', 'vertical');
            grid(app.inletFluidTempPlot, 'on');
            hold(app.inletFluidTempPlot, 'on');
            
            % Flow rate plot
            app.flowRatePlot.XLimMode       = 'manual';
            app.flowRatePlot.YLimMode       = 'auto';
            app.flowRatePlot.XLim           = [0 app.configuration.plotSeconds];
            app.flowRateLineObject          = animatedline(app.flowRatePlot,"Color", 'k',"LineStyle","-", "DisplayName","Flow Rate");
            legend(app.flowRatePlot,'Location', 'northwest', 'Orientation', 'vertical');
            grid(app.flowRatePlot, 'on');
            hold(app.flowRatePlot, 'on');
        end
        
        function updateVisuals(app, ~, ~)
            if (length(app.teensy.UserData.time) > 2)
                % Get the correct x limits 
                timeElapsed = app.teensy.UserData.time(end)-app.teensy.UserData.time(1);
                if (timeElapsed < (app.configuration.plotSeconds*0.9))
                    Xmin = 0;
                    Xmax = app.configuration.plotSeconds;
                    app.variables.dTime = app.teensy.UserData.time(end)-app.teensy.UserData.time(end-1);
                    app.variables.numShowData = int8(timeElapsed/app.variables.dTime);
                else
                    Xmin = timeElapsed - app.configuration.plotSeconds;
                    Xmax = Xmin + app.configuration.plotSeconds*1.1;
                end
                   
                % Inlet fluid temp plot
                hold(app.inletFluidTempPlot, 'on');
                app.inletFluidTempPlot.XLim = [Xmin Xmax];
                hold(app.inletFluidTempPlot, 'off');
                
                % Inlet flow rate plot
                hold(app.flowRatePlot, 'on');
                app.flowRatePlot.XLim = [Xmin Xmax];
                hold(app.flowRatePlot, 'off');
                
                % Draw new points quickly
                drawnow limitrate
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; close all;
            % Filename for log file
            app.debugFile.fileName                      = strjoin({pwd, '/logs/HX1_' datestr(now,'mm-dd-yy__HH_MM_SS') '.txt'}, '');
            app.iTextBox                                = 0;
            app.variables.connected                     = false;
            app.variables.firstData                     = true;
            app.variables.updateData                    = true;
            app.variables.dataJustSent                  = false;
            app.variables.countJustSent                 = 0;
            app.variables.endTesting                    = 1;
            app.variables.teensy_delimiter              = ',';
            app.variables.teensy_terminator             = '?';
            app.variables.lightRedColor                 = [0.95 0.39 0.39];
            app.variables.lightGreenColor               = [0.42 0.85 0.42];
            app.variables.darkRedColor                  = [1 0 0];
            app.variables.darkGreenColor                = [0 1 0];
            app.variables.defaultBackgroundColor        = [0.94 0.94 0.94];
            app.SendControlstoTeensyButton.Enable       = 0;
            
            % Set panel background colors
            app.TeensyConnectionSettingsPanel.BackgroundColor   = app.variables.lightRedColor;
            app.SwitchBackgroundColor.BackgroundColor           = app.variables.lightRedColor;
            
            % Load the last variables saved in the configuration.mat file
            displayDebug(app, 'Loading configuration.mat');
            load configuration.mat BAUD comPort plotSeconds plotUpdateTime readVarCount readVarType sendVarCount sendVarType;
            % Save the variables from configuration.mat in the  
            % configuration property
            app.configuration.comPort           = comPort;
            app.configuration.BAUD              = BAUD;
            app.configuration.readVarCount      = readVarCount;
            app.configuration.sendVarCount      = sendVarCount;
            app.configuration.readVarType       = readVarType;
            app.configuration.sendVarType       = sendVarType;
            app.configuration.plotSeconds       = plotSeconds;        %seconds
            app.configuration.plotUpdateTime    = plotUpdateTime;     %seconds
            displayDebug(app, 'Loaded configuration.mat');
            
            % Load PID values from PIDvalues.mat
            load PIDvalues.mat Ki Kd Kp;
            app.configuration.Kp                = Kp;
            app.configuration.Ki                = Ki;
            app.configuration.Kd                = Kd;
            app.variables.Kp                    = Kp;
            app.variables.Ki                    = Ki;
            app.variables.Kd                    = Kd;
            displayDebug(app, 'Loaded PIDvalues.mat');
                        
            % Create timer for plot update
            app.plotTimer = timer;
            app.plotTimer.ExecutionMode     = 'fixedRate';
            app.plotTimer.Period            = plotUpdateTime;
            app.plotTimer.BusyMode          = 'queue';
            app.plotTimer.TimerFcn          = @app.updateVisuals;
            
            % Initialize the visuals
            initVisuals(app);
            
            % Get serial port lists
            getSerialPortList(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % If plot timer running, stop it
            try
                if strcmp(app.plotTimer.Running, 'on')
                    stop(app.plotTimer);
                    % Send endTesting to teensy
                    app.variables.endTesting = 1;
                    sendDataTeensy(app);
                    % Save the current test if running
                    displayDebug(app, 'Stopping current test');     % Stop the test
                    saveData(app);                                  % Saves data to file
                end
                delete(app.plotTimer);      % Delete timer object
            catch
                displayDebug(app, 'Error while stopping plot timer');
                delete(app.plotTimer)
            end
            
            % Save configuration.mat file
            comPort         = app.configuration.comPort;
            BAUD            = app.configuration.BAUD;
            readVarCount    = app.configuration.readVarCount;
            sendVarCount    = app.configuration.sendVarCount;
            readVarType     = app.configuration.readVarType;
            sendVarType     = app.configuration.sendVarType;
            plotSeconds     = app.configuration.plotSeconds;
            plotUpdateTime  = app.configuration.plotUpdateTime; 
            save('configurationTEST.mat', 'BAUD', 'comPort', 'plotSeconds', 'plotUpdateTime', 'readVarCount', ...
            'readVarType', 'sendVarCount', 'sendVarType', '-v7.3');
            displayDebug(app, 'Saved COM values to configuration.mat');
            
            % Save PID values
            Kd              = app.variables.Kd;
            Kp              = app.variables.Kp;
            Ki              = app.variables.Ki;
            save('PIDvalues.mat', 'Kp', 'Ki', 'Kd', '-V7.3');
            displayDebug(app, 'Saved PID values to PIDvalues.mat');

            % Save the log file
            logSave = fopen(app.debugFile.fileName, 'w');
            for i=1:length(app.debugFile.text)
                fprintf(logSave,'%s\n',char(app.debugFile.text(i)));
            end
            fclose(logSave);
            
            % Close app
            delete(app)            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Check if the variables are valid
            comPort = app.COMPortDropDown.Value;
            baudRate = app.BaudrateDropDown.Value;
            goodComPort = false;
            goodBaudRate = false;

            if (any(strcmp(app.configuration.comList, comPort)))
                goodComPort = true;
            end
            if ~(strcmp("Select", baudRate))
                goodBaudRate = true;
                baudRate = str2num(baudRate); %#ok<ST2NM>
            end

            % Send data to the main app if good
            if (goodComPort && goodBaudRate)
                message = strjoin({'using' comPort '@' int2str(baudRate)});
                displayDebug(app, message);
                app.configuration.comPort   = comPort;
                app.configuration.BAUD      = baudRate;
                try
                    initTeensyConnection(app);
                    app.TeensyConnectionSettingsPanel.BackgroundColor = app.variables.lightGreenColor;
                    app.ConnectButton.Enable = 0;           % Disable connect button
                    app.RefreshButton.Enable = 0;           % Disable refresh button
                    app.COMPortDropDown.Enable = 0;         % Disable com port dropdown
                    app.BaudrateDropDown.Enable = 0;        % Disable baudrate dropdown
                    app.DefaultConnectSettings.Enable = 0;  % Disable default connect settings checkbox
                    app.variables.connected = true;
                catch
                    displayDebug(app, 'Fatal error with Teensy init');
                    app.TeensyConnectionSettingsPanel.BackgroundColor = app.variables.lightRedColor;
                end
            else
                displayDebug(app, 'Connection settings illegal');                
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.DefaultConnectSettings.Value = 0;
            getSerialPortList(app);
        end

        % Value changed function: DefaultConnectSettings
        function DefaultConnectSettingsValueChanged(app, event)
            value = app.DefaultConnectSettings.Value;
            if value == 1
                % Check if the default COM port is in current list, if not dispaly error
                comPort = app.configuration.comPort;
                if ~(any(strcmp(app.configuration.comList, comPort)))
                    displayDebug(app, 'Default COM port is not available');
                    app.DefaultConnectSettings.Value = 0;
                else
                    app.BaudrateDropDown.Value    = num2str(app.configuration.BAUD);
                    app.COMPortDropDown.Value     = app.configuration.comPort;
                    displayDebug(app, 'Valid default settings');
                end
            else
                getSerialPortList(app);
            end
        end

        % Value changed function: StartStopSwitch
        function StartStopSwitchValueChanged(app, event)
            value = app.StartStopSwitch.Value;
            if (value == "Start")
                % First check if connected
                if (app.variables.connected)
                    % Create file name for test
                    app.variables.fileName = strjoin({pwd, '/data/HX1_' datestr(now,'mm-dd-yy__HH_MM_SS') '.csv'}, '');
                    % Initiate visuals again
                    initVisuals(app);          
                    % Start the timer for plot update
                    if strcmp(app.plotTimer.Running, 'off')
                        start(app.plotTimer);
                    end
                    % Update endTesting var and PID values on teensy
                    app.variables.endTesting = 0;
                    app.variables.Kp = app.configuration.Kp;
                    app.variables.Ki = app.configuration.Ki;
                    app.variables.Kd = app.configuration.Kd;
                    sendDataTeensy(app);
                    % Start the data aquisistion
                    displayDebug(app, 'Starting data logging');
                    app.SwitchBackgroundColor.BackgroundColor = app.variables.lightGreenColor;
            else
                    displayDebug(app, 'Not connected to Teensy yet');
                    app.StartStopSwitch.Value = "Stop & Save";
                end
            else
                app.SwitchBackgroundColor.BackgroundColor = app.variables.lightRedColor;
                % If plot timer running, stop it
                if strcmp(app.plotTimer.Running, 'on')
                    stop(app.plotTimer);
                end
                app.variables.endTesting = 1;
                sendDataTeensy(app);                            % Tell teensy test is ended
                displayDebug(app, 'Stopping current test');     % Stop the test
                saveData(app);                                  % Saves data to file
            end
            
        end

        % Callback function
        function HeatFluxValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: targetInletFluidTemp
        function targetInletFluidTempValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Callback function
        function HeaterModulesSwitchValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: RopeHeaterSwitch
        function RopeHeaterSwitchValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Callback function
        function Enable2ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Callback function
        function Frequency2ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Callback function
        function Amplitude2ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Callback function
        function Phase2ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: KpBox
        function KpBoxValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: KiBox
        function KiBoxValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: KdBox
        function KdBoxValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Callback function
        function Enable1ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor; 
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Button pushed function: SendControlstoTeensyButton
        function SendControlstoTeensyButtonPushed(app, event)
            sendDataTeensy(app);
            app.UpdateControlsPanel.BackgroundColor = app.variables.defaultBackgroundColor;
            app.SendControlstoTeensyButton.Enable = 0;
            app.variables.dataJustSent = 1;
            %disp([app.variables.Kp, app.variables.Ki, app.variables.Kd]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {739, 739, 739};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {739, 739};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {264, '1x', 310};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.TeensyConnectionSettingsPanel = uipanel(app.GridLayout3);
            app.TeensyConnectionSettingsPanel.Title = 'Teensy Connection Settings';
            app.COMPortDropDownLabel = uilabel(app.TeensyConnectionSettingsPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.TeensyConnectionSettingsPanel);
            app.BaudrateDropDownLabel = uilabel(app.TeensyConnectionSettingsPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.TeensyConnectionSettingsPanel);
            app.DefaultConnectSettings = uicheckbox(app.TeensyConnectionSettingsPanel);
            app.DefaultConnectSettings.ValueChangedFcn = createCallbackFcn(app, @DefaultConnectSettingsValueChanged, true);
            app.DefaultConnectSettings.Text = 'Default connection settings';
            app.RefreshButton = uibutton(app.TeensyConnectionSettingsPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ConnectButton = uibutton(app.TeensyConnectionSettingsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.HeaterControlPanel = uipanel(app.GridLayout3);
            app.HeaterControlPanel.Title = 'Heater Control';
            app.InletFluidTempCEditFieldLabel = uilabel(app.HeaterControlPanel);
            app.InletFluidTempCEditFieldLabel.Text = 'Inlet Fluid Temp (C)';
            app.targetInletFluidTemp = uieditfield(app.HeaterControlPanel, 'numeric');
            app.targetInletFluidTemp.ValueChangedFcn = createCallbackFcn(app, @targetInletFluidTempValueChanged, true);
            app.RopeHeaterSwitchLabel = uilabel(app.HeaterControlPanel);
            app.RopeHeaterSwitchLabel.Text = 'Rope Heater';
            app.RopeHeaterSwitch = uiswitch(app.HeaterControlPanel, 'slider');
            app.RopeHeaterSwitch.ValueChangedFcn = createCallbackFcn(app, @RopeHeaterSwitchValueChanged, true);
            app.SwitchBackgroundColor = uiimage(app.GridLayout3);
            app.SwitchBackgroundColor.ImageSource = 'background.png';
            app.PIDTunePanel = uipanel(app.GridLayout3);
            app.PIDTunePanel.Title = 'PID Tune';
            app.KpLabel = uilabel(app.PIDTunePanel);
            app.KpLabel.Text = 'Kp';
            app.KpBox = uieditfield(app.PIDTunePanel, 'numeric');
            app.KpBox.ValueChangedFcn = createCallbackFcn(app, @KpBoxValueChanged, true);
            app.KiLabel_2 = uilabel(app.PIDTunePanel);
            app.KiLabel_2.Text = 'Ki';
            app.KiBox = uieditfield(app.PIDTunePanel, 'numeric');
            app.KiBox.ValueChangedFcn = createCallbackFcn(app, @KiBoxValueChanged, true);
            app.KdLabel = uilabel(app.PIDTunePanel);
            app.KdLabel.Text = 'Kd';
            app.KdBox = uieditfield(app.PIDTunePanel, 'numeric');
            app.KdBox.ValueChangedFcn = createCallbackFcn(app, @KdBoxValueChanged, true);
            app.KpDefinition = uilabel(app.PIDTunePanel);
            app.KpDefinition.Text = 'Proportional term produces an output value that is proportional to the current error value';
            app.KiDefinition = uilabel(app.PIDTunePanel);
            app.KiDefinition.Text = 'Integral term is proportional to both the magnitude of the error and the duration of the error';
            app.KdDefinition = uilabel(app.PIDTunePanel);
            app.KdDefinition.Text = 'Derivative action predicts system behavior and thus improves settling time and stability of the system';
            app.StartStopSwitch = uiswitch(app.GridLayout3, 'slider');
            app.StartStopSwitch.Items = {'Stop & Save', 'Start'};
            app.StartStopSwitch.ValueChangedFcn = createCallbackFcn(app, @StartStopSwitchValueChanged, true);
            app.StartStopSwitch.Value = 'Stop & Save';
            app.UpdateControlsPanel = uipanel(app.GridLayout3);
            app.UpdateControlsPanel.Title = 'Update Controls';
            app.SendControlstoTeensyButton = uibutton(app.UpdateControlsPanel, 'push');
            app.SendControlstoTeensyButton.ButtonPushedFcn = createCallbackFcn(app, @SendControlstoTeensyButtonPushed, true);
            app.SendControlstoTeensyButton.Text = {'Send Controls to Teensy'; ''};
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.flowRatePlot = uiaxes(app.GridLayout2);
            title(app.flowRatePlot, 'Flow Rate')
            xlabel(app.flowRatePlot, 'Time (s)')
            ylabel(app.flowRatePlot, 'Flow Rate (mL/min)')
            zlabel(app.flowRatePlot, 'Z')
            app.inletFluidTempPlot = uiaxes(app.GridLayout2);
            title(app.inletFluidTempPlot, {'Inlet Fluid Temperature'; ''})
            xlabel(app.inletFluidTempPlot, 'Time (s)')
            ylabel(app.inletFluidTempPlot, 'Temperature (C)')
            zlabel(app.inletFluidTempPlot, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.DebugWindowPanel = uipanel(app.GridLayout4);
            app.DebugWindowPanel.Title = 'Debug Window';
            app.DebugTextArea = uitextarea(app.DebugWindowPanel);
            app.DebugTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIDTune

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end