classdef comPortSelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RefreshButton                matlab.ui.control.Button
        OkButton                     matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        ChooseCOMPortDropDownLabel   matlab.ui.control.Label
        ChooseCOMPortDropDown        matlab.ui.control.DropDown
        ChooseBaudRateDropDownLabel  matlab.ui.control.Label
        ChooseBaudRateDropDown       matlab.ui.control.DropDown
        UseDefaultInputsCheckBox     matlab.ui.control.CheckBox
        Warning                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        default         % default vars from configuration.mat file
        CallingApp      % Main app object
    end
    
    methods (Access = private)
        
        function getSerialPortList(app)
            serialPortList = serialportlist("available");
            list = ["Select"]; %#ok<NBRAK> 
            for i=1:length(serialPortList)
                list(end+1) = serialPortList(i); %#ok<AGROW> 
            end
            app.default.comList = list;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, defaultComPort, defaultBAUD)
            app.CallingApp = mainapp;
            
            app.default.BAUD        = num2str(defaultBAUD);
            app.default.comPort     = defaultComPort;
            getSerialPortList(app);
            app.CallingApp.DisplayDebug('opened COM dialogue GUI');
            
            
            app.ChooseBaudRateDropDown.Items    = ["Select", "1200", "2400", "4800", "9600", "19200", "38400", "57600", "115200"];
            app.ChooseCOMPortDropDown.Items     = app.default.comList;
        end

        % Value changed function: UseDefaultInputsCheckBox
        function UseDefaultInputsCheckBoxValueChanged(app, event)
            value = app.UseDefaultInputsCheckBox.Value;
            if value == 1
                % Check if the default COM port is in current list, if not dispaly error
                comPort = app.default.comPort;
                if ~(any(strcmp(app.default.comList, comPort)))
                    app.Warning.Text = "Default COM port is not available";
                    app.UseDefaultInputsCheckBox.Value = 0;
                else
                    app.ChooseBaudRateDropDown.Value    = app.default.BAUD;
                    app.ChooseCOMPortDropDown.Value     = app.default.comPort;
                    app.Warning.Visible                 = 0;
                end
            else
                getSerialPortList(app);
                app.ChooseBaudRateDropDown.Items    = ["Select", "1200", "2400", "4800", "9600", "19200", "38400", "57600", "115200"];
                app.ChooseCOMPortDropDown.Items     = app.default.comList;
                app.ChooseBaudRateDropDown.Value    = "Select";
                app.ChooseCOMPortDropDown.Value     = "Select";                
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            getSerialPortList(app);
            app.ChooseCOMPortDropDown.Items = app.default.comList;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            printToCommandWindow(app, ["Hello"]);
            % Check if the variables are valid
            comPort = app.ChooseBaudRateDropDown.Value;
            baudRate = app.ChooseBaudRateDropDown.Value;
            goodComPort = false;
            goodBaudRate = false;
            
            if (any(strcmp(app.default.comList, comPort)))
                goodComPort = true;
                disp("good COM port");
            end
            if ~(strcmp("Select", baudRate))
                goodBaudRate = true;        
                baudRate = str2num(baudRate); %#ok<ST2NM>
                disp("good baud rate");
            end
            
            % Send data to the main app if good
            if (goodComPort && goodBaudRate)
                disp("sending to main app");
                app.CallingApp.initTeensy(app.CallingApp, comPort, baudRate);
                disp("sent to mainapp");
            else
                app.Warning.Text = "Given inputs are not legal, please select legal values";
            end
            
            % Close dialogue app
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Close both apps
            %delete(app.CallingApp)
            delete(app) 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close both apps
            %delete(app.CallingApp)
            delete(app)    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ChooseCOMPortDropDownLabel = uilabel(app.UIFigure);
            app.ChooseCOMPortDropDownLabel.Text = 'Choose COM Port';
            app.ChooseCOMPortDropDown = uidropdown(app.UIFigure);
            app.ChooseBaudRateDropDownLabel = uilabel(app.UIFigure);
            app.ChooseBaudRateDropDownLabel.Text = 'Choose Baud Rate';
            app.ChooseBaudRateDropDown = uidropdown(app.UIFigure);
            app.UseDefaultInputsCheckBox = uicheckbox(app.UIFigure);
            app.UseDefaultInputsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDefaultInputsCheckBoxValueChanged, true);
            app.UseDefaultInputsCheckBox.Text = 'Use Default Inputs';
            app.Warning = uilabel(app.UIFigure);
            app.Warning.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = comPortSelect(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end