classdef hxV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        FrequencyHzEditFieldLabel      matlab.ui.control.Label
        Frequency1                     matlab.ui.control.NumericEditField
        Piezo1SwitchLabel              matlab.ui.control.Label
        Piezo1Switch                   matlab.ui.control.Switch
        Piezo2SwitchLabel              matlab.ui.control.Label
        Piezo2Switch                   matlab.ui.control.Switch
        PhasedegEditFieldLabel         matlab.ui.control.Label
        Phase1                         matlab.ui.control.NumericEditField
        HeatFluxWcm2EditFieldLabel     matlab.ui.control.Label
        HeatFlux                       matlab.ui.control.NumericEditField
        Piezo2PropertiesLabel          matlab.ui.control.Label
        FrequencyHzEditField_2Label    matlab.ui.control.Label
        Frequency2                     matlab.ui.control.NumericEditField
        PhasedegEditField_2Label       matlab.ui.control.Label
        Phase2                         matlab.ui.control.NumericEditField
        AmplitudeVEditField_2Label     matlab.ui.control.Label
        HeaterControlsLabel            matlab.ui.control.Label
        Amplitude2                     matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel       matlab.ui.control.Label
        Amplitude1                     matlab.ui.control.NumericEditField
        Piezo1PropertiesLabel          matlab.ui.control.Label
        InletFluidTempCEditFieldLabel  matlab.ui.control.Label
        InletFluidTemp                 matlab.ui.control.NumericEditField
        HeaterModulesSwitchLabel       matlab.ui.control.Label
        HeaterSwitch                   matlab.ui.control.Switch
        RopeHeaterSwitchLabel          matlab.ui.control.Label
        RopeHeaterSwitch               matlab.ui.control.Switch
        Label                          matlab.ui.control.Label
        StartStopSwitch                matlab.ui.control.Switch
        CenterPanel                    matlab.ui.container.Panel
        bsTemp                         matlab.ui.control.UIAxes
        flowRate                       matlab.ui.control.UIAxes
        outPressure                    matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        teensy % Description
        configuration % Description
        ComPortApp % Description
        filesave % Description
    end
    
    methods (Access = private)
               
        function initDataAquisition(app)
            app.teensy.UserData = struct("Time",[], ...
                                         "inletPressureUpstream",[], ...
                                         "inletPressureDownstream",[], ...
                                         "outletPressureVapor",[], ...
                                         "outletPressureLiquid",[], ...
                                         "heaterTemperature1",[], ...
                                         "heaterTemperature2",[], ...
                                         "heaterTemperature3",[], ...
                                         "heaterTemperature4",[], ...
                                         "heaterTemperature5",[], ...
                                         "boilSurfaceTemperature1",[], ...
                                         "boilSurfaceTemperature2",[], ...
                                         "boilSurfaceTemperature3",[], ...
                                         "boilSurfaceTemperature4",[], ...
                                         "averageBoilSurfaceTemp",[], ...
                                         "inletFlowRate",[], ...
                                         "inletFluidTemperature",[], ...
                                         "frequency1",[], ...
                                         "frequency2",[], ...
                                         "amplitude1",[], ...
                                         "amplitude2",[], ...
                                         "phase1",[], ...
                                         "phase2",[], ...
                                         "enable1",[], ...
                                         "enable2",[]);
        end
        
        function saveData(app)
            
        end
        
        function sendDataTeensy(app)
            
        end
        
        function runDataAquisition(app)
            while (app.StartStopSwitch.Value == "Start")
                if (app.teensy.NumBytesAvailable > 0)
                    data = str2double(split(readline(app.teensy), ","));
                    i = 1;
                    app.teensy.UserData.Time(end+1)                     = data(i); i=i+1;
                    app.teensy.UserData.inletPressureUpstream(end+1)    = data(i); i=i+1;
                    app.teensy.UserData.inletPressureDownstream(end+1)  = data(i); i=i+1;
                    app.teensy.UserData.outletPressureVapor(end+1)      = data(i); i=i+1;
                    app.teensy.UserData.outletPressureLiquid(end+1)     = data(i); i=i+1;
                    app.teensy.UserData.heaterTemperature1(end+1)       = data(i); i=i+1;
                    app.teensy.UserData.heaterTemperature2(end+1)       = data(i); i=i+1;
                    app.teensy.UserData.heaterTemperature3(end+1)       = data(i); i=i+1;
                    app.teensy.UserData.heaterTemperature4(end+1)       = data(i); i=i+1;
                    app.teensy.UserData.heaterTemperature5(end+1)       = data(i); i=i+1;
                    app.teensy.UserData.boilSurfaceTemperature1(end+1)  = data(i); i=i+1;
                    app.teensy.UserData.boilSurfaceTemperature2(end+1)  = data(i); i=i+1;
                    app.teensy.UserData.boilSurfaceTemperature3(end+1)  = data(i); i=i+1;
                    app.teensy.UserData.boilSurfaceTemperature4(end+1)  = data(i); i=i+1;
                    app.teensy.UserData.averageBoilSurfaceTemp(end+1)   = data(i); i=i+1;
                    app.teensy.UserData.inletFlowRate(end+1)            = data(i); i=i+1;
                    app.teensy.UserData.inletFluidTemperature(end+1)    = data(i); i=i+1;
                    app.teensy.UserData.frequency1(end+1)               = data(i); i=i+1;
                    app.teensy.UserData.frequency2(end+1)               = data(i); i=i+1;
                    app.teensy.UserData.amplitude1(end+1)               = data(i); i=i+1;
                    app.teensy.UserData.amplitude2(end+1)               = data(i); i=i+1;
                    app.teensy.UserData.phase1(end+1)                   = data(i); i=i+1;
                    app.teensy.UserData.phase2(end+1)                   = data(i); i=i+1;
                    app.teensy.UserData.enable1(end+1)                  = data(i); i=i+1;
                    app.teensy.UserData.enable2(end+1)                  = data(i); i=i+1;
                    app.teensy.UserData.endTesting(end+1)               = data(i);
                end
                udatePlots(app);
            end
        end
        
        function initTeensy(app, comPort, baudRate)
            app.configuration.comPort       = comPort;
            app.configuration.BAUD          = baudRate;
            app.teensy = serialport(comPort, baudRate);     % Create teensy opject
            configureTerminator(app.teensy, "CR/LF");       % Terminator set in teensy code
            flush(app.teensy);                              % Flush serial bits
        end
        
        function updatePlots(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the last variables saved in the configuration.mat file
            load('configuration.mat'); %#ok<LOAD> 
            % Save the variables from configuration.mat in the
            % configuration property
            app.configuration.comPort           = comPort;
            app.configuration.BAUD              = BAUD;
            app.configuration.readVarCount      = readVarCount;
            app.configuration.sendVarCount      = sendVarCount;
            app.configuration.readVarType       = readVarType;
            app.configuration.sendVarType       = sendVarType;
            app.configuration.plotSeconds       = plotSeconds;
            % Open dialogue box to get the baud rate and com port from user
            app.ComPortApp = comPortSelect(app, comPort, BAUD);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {594, 594, 594};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {594, 594};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {267, '1x', 166};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: Frequency1
        function Frequency1ValueChanged(app, event)
            value = app.Frequency1.Value;
            
        end

        % Value changed function: Frequency2
        function Frequency2ValueChanged(app, event)
            value = app.Frequency2.Value;
            
        end

        % Value changed function: Piezo1Switch
        function Piezo1SwitchValueChanged(app, event)
            value = app.Piezo1Switch.Value;
            
        end

        % Value changed function: Piezo2Switch
        function Piezo2SwitchValueChanged(app, event)
            value = app.Piezo2Switch.Value;
            
        end

        % Value changed function: Phase1
        function Phase1ValueChanged(app, event)
            value = app.Phase1.Value;
            
        end

        % Value changed function: Phase2
        function Phase2ValueChanged(app, event)
            value = app.Phase2.Value;
            
        end

        % Value changed function: Amplitude1
        function Amplitude1ValueChanged(app, event)
            value = app.Amplitude1.Value;
            
        end

        % Value changed function: Amplitude2
        function Amplitude2ValueChanged(app, event)
            value = app.Amplitude2.Value;
            
        end

        % Value changed function: HeatFlux
        function HeatFluxValueChanged(app, event)
            value = app.HeatFlux.Value;
            
        end

        % Value changed function: InletFluidTemp
        function InletFluidTempValueChanged(app, event)
            value = app.InletFluidTemp.Value;
            
        end

        % Value changed function: HeaterSwitch
        function HeaterSwitchValueChanged(app, event)
            value = app.HeaterSwitch.Value;
            
        end

        % Value changed function: RopeHeaterSwitch
        function RopeHeaterSwitchValueChanged(app, event)
            value = app.RopeHeaterSwitch.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Save configuration.mat file
            comPort         = app.configuration.comPort;
            BAUD            = app.configuration.BAUD;
            readVarCount    = app.configuration.readVarCount;
            sendVarCount    = app.configuration.sendVarCount;
            readVarType     = app.configuration.readVarType;
            sendVarType     = app.configuration.sendVarType;
            plotSeconds     = app.configuration.plotSeconds;
            save configuration.mat comPort BAUD readVarCount sendVarCount readVarType sendVarType plotSeconds -v7.3
        
            % Close both apps
            delete(app.ComPortApp)
            delete(app)
        end

        % Value changed function: StartStopSwitch
        function StartStopSwitchValueChanged(app, event)
            value = app.StartStopSwitch.Value;
            if (value == "Start")
                % Create file name for test
                app.filename.fileName = append('HX1_', datestr(now,'mm-dd-yy__HH_MM_SS.FFF'));
                % Start the data aquisistion
                runDataAquisition(app);
            else
                % Save the data
                saveData(app);
                % Re-Initiate the data struct
                initDataAquisition(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FrequencyHzEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyHzEditFieldLabel.Text = {'Frequency (Hz)'; ''};
            app.Frequency1 = uieditfield(app.LeftPanel, 'numeric');
            app.Frequency1.ValueChangedFcn = createCallbackFcn(app, @Frequency1ValueChanged, true);
            app.Piezo1SwitchLabel = uilabel(app.LeftPanel);
            app.Piezo1SwitchLabel.Text = 'Piezo 1';
            app.Piezo1Switch = uiswitch(app.LeftPanel, 'slider');
            app.Piezo1Switch.ValueChangedFcn = createCallbackFcn(app, @Piezo1SwitchValueChanged, true);
            app.Piezo2SwitchLabel = uilabel(app.LeftPanel);
            app.Piezo2SwitchLabel.Text = 'Piezo 2';
            app.Piezo2Switch = uiswitch(app.LeftPanel, 'slider');
            app.Piezo2Switch.ValueChangedFcn = createCallbackFcn(app, @Piezo2SwitchValueChanged, true);
            app.PhasedegEditFieldLabel = uilabel(app.LeftPanel);
            app.PhasedegEditFieldLabel.Text = {'Phase (deg)'; ''};
            app.Phase1 = uieditfield(app.LeftPanel, 'numeric');
            app.Phase1.ValueChangedFcn = createCallbackFcn(app, @Phase1ValueChanged, true);
            app.HeatFluxWcm2EditFieldLabel = uilabel(app.LeftPanel);
            app.HeatFluxWcm2EditFieldLabel.Text = 'Heat Flux (W/cm^2)';
            app.HeatFlux = uieditfield(app.LeftPanel, 'numeric');
            app.HeatFlux.ValueChangedFcn = createCallbackFcn(app, @HeatFluxValueChanged, true);
            app.AmplitudeVEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (V)';
            app.Amplitude1 = uieditfield(app.LeftPanel, 'numeric');
            app.Amplitude1.ValueChangedFcn = createCallbackFcn(app, @Amplitude1ValueChanged, true);
            app.Piezo1PropertiesLabel = uilabel(app.LeftPanel);
            app.Piezo1PropertiesLabel.Text = 'Piezo 1 Properties';
            app.Piezo2PropertiesLabel = uilabel(app.LeftPanel);
            app.Piezo2PropertiesLabel.Text = 'Piezo 2 Properties';
            app.FrequencyHzEditField_2Label = uilabel(app.LeftPanel);
            app.FrequencyHzEditField_2Label.Text = {'Frequency (Hz)'; ''};
            app.Frequency2 = uieditfield(app.LeftPanel, 'numeric');
            app.Frequency2.ValueChangedFcn = createCallbackFcn(app, @Frequency2ValueChanged, true);
            app.PhasedegEditField_2Label = uilabel(app.LeftPanel);
            app.PhasedegEditField_2Label.Text = {'Phase (deg)'; ''};
            app.Phase2 = uieditfield(app.LeftPanel, 'numeric');
            app.Phase2.ValueChangedFcn = createCallbackFcn(app, @Phase2ValueChanged, true);
            app.AmplitudeVEditField_2Label = uilabel(app.LeftPanel);
            app.AmplitudeVEditField_2Label.Text = 'Amplitude (V)';
            app.Amplitude2 = uieditfield(app.LeftPanel, 'numeric');
            app.Amplitude2.ValueChangedFcn = createCallbackFcn(app, @Amplitude2ValueChanged, true);
            app.HeaterControlsLabel = uilabel(app.LeftPanel);
            app.HeaterControlsLabel.Text = 'Heater Controls';
            app.InletFluidTempCEditFieldLabel = uilabel(app.LeftPanel);
            app.InletFluidTempCEditFieldLabel.Text = 'Inlet Fluid Temp (C)';
            app.InletFluidTemp = uieditfield(app.LeftPanel, 'numeric');
            app.InletFluidTemp.ValueChangedFcn = createCallbackFcn(app, @InletFluidTempValueChanged, true);
            app.HeaterModulesSwitchLabel = uilabel(app.LeftPanel);
            app.HeaterModulesSwitchLabel.Text = 'Heater Modules';
            app.HeaterSwitch = uiswitch(app.LeftPanel, 'slider');
            app.HeaterSwitch.ValueChangedFcn = createCallbackFcn(app, @HeaterSwitchValueChanged, true);
            app.RopeHeaterSwitchLabel = uilabel(app.LeftPanel);
            app.RopeHeaterSwitchLabel.Text = 'Rope Heater';
            app.RopeHeaterSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RopeHeaterSwitch.ValueChangedFcn = createCallbackFcn(app, @RopeHeaterSwitchValueChanged, true);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.StartStopSwitch = uiswitch(app.LeftPanel, 'slider');
            app.StartStopSwitch.Items = {'Stop & Save', 'Start'};
            app.StartStopSwitch.ValueChangedFcn = createCallbackFcn(app, @StartStopSwitchValueChanged, true);
            app.StartStopSwitch.Value = 'Stop & Save';
            app.CenterPanel = uipanel(app.GridLayout);
            app.bsTemp = uiaxes(app.CenterPanel);
            title(app.bsTemp, 'Boil Surface Temp')
            xlabel(app.bsTemp, 'Time (s)')
            ylabel(app.bsTemp, 'Temperature (C)')
            app.flowRate = uiaxes(app.CenterPanel);
            title(app.flowRate, 'Flow Rate')
            xlabel(app.flowRate, 'Time (s)')
            ylabel(app.flowRate, 'Instantaneous Flow Rate (mL/min)')
            app.outPressure = uiaxes(app.CenterPanel);
            title(app.outPressure, 'Outlet Pressure')
            xlabel(app.outPressure, 'Time (s)')
            ylabel(app.outPressure, 'Pressure (psia)')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hxV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end